classdef GalvoControlPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GalvoControlPanelUIFigure      matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        menu_reconnect                 matlab.ui.container.Menu
        menu_disconnect                matlab.ui.container.Menu
        menu_debug                     matlab.ui.container.Menu
        menu_assignWorkspace           matlab.ui.container.Menu
        menu_about                     matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        StaticTab                      matlab.ui.container.Tab
        VoltageY_decreaseSmall_2       matlab.ui.control.Button
        Setto00Button                  matlab.ui.control.Button
        VoltageX_dereaseMedium         matlab.ui.control.Button
        VoltageX_dereaseLarge          matlab.ui.control.Button
        VoltageX_dereaseSmall          matlab.ui.control.Button
        VoltageX_increaseLarge         matlab.ui.control.Button
        VoltageX_increaseMedium        matlab.ui.control.Button
        VoltageX_increaseSmall         matlab.ui.control.Button
        VoltageY_decreaseMedium        matlab.ui.control.Button
        VoltageY_decreaseLarge         matlab.ui.control.Button
        VoltageY_decreaseSmall         matlab.ui.control.Button
        VoltageY_increaseLarge         matlab.ui.control.Button
        VoltageY_increaseMedium        matlab.ui.control.Button
        VoltageY_increaseSmall         matlab.ui.control.Button
        CurrentVoltageYEditField       matlab.ui.control.NumericEditField
        CurrentVoltageYEditFieldLabel  matlab.ui.control.Label
        CurrentVoltageXEditField       matlab.ui.control.NumericEditField
        CurrentVoltageXEditFieldLabel  matlab.ui.control.Label
        DynamicTab                     matlab.ui.container.Tab
        dropdown_waveform              matlab.ui.control.DropDown
        WaveformDropDownLabel          matlab.ui.control.Label
        GalvoRate                      matlab.ui.control.NumericEditField
        RepeatsLabel_2                 matlab.ui.control.Label
        Repeats                        matlab.ui.control.NumericEditField
        RepeatsLabel                   matlab.ui.control.Label
        LineScanMode                   matlab.ui.container.ButtonGroup
        Alternating_2                  matlab.ui.control.ToggleButton
        Alternating                    matlab.ui.control.ToggleButton
        AlongY                         matlab.ui.control.ToggleButton
        AlongX                         matlab.ui.control.ToggleButton
        VoltageY_Static                matlab.ui.control.NumericEditField
        StaticYEditFieldLabel          matlab.ui.control.Label
        VoltageX_Static                matlab.ui.control.NumericEditField
        StaticXEditFieldLabel          matlab.ui.control.Label
        VoltageY_Amplitude             matlab.ui.control.NumericEditField
        AmplitudeYEditFieldLabel       matlab.ui.control.Label
        VoltageX_Amplitude             matlab.ui.control.NumericEditField
        AmplitudeXEditFieldLabel       matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        RasterScanTab                  matlab.ui.container.Tab
        dropdown_RasterScan_FastAxis   matlab.ui.control.DropDown
        FastaxisLabel                  matlab.ui.control.Label
        dropdown_RasterScan_Waveform   matlab.ui.control.DropDown
        WaveformDropDownLabel_2        matlab.ui.control.Label
        edit_RasterScan_OffsetY        matlab.ui.control.NumericEditField
        PixelStepsizeVLabel_4          matlab.ui.control.Label
        edit_RasterScan_OffsetX        matlab.ui.control.NumericEditField
        PixelStepsizeVLabel_3          matlab.ui.control.Label
        edit_RasterScan_ScanPoints     matlab.ui.control.NumericEditField
        PixelStepsizeVLabel_2          matlab.ui.control.Label
        edit_RasterScan_ScanTime       matlab.ui.control.NumericEditField
        RepeatsLabel_7                 matlab.ui.control.Label
        edit_RasterScan_DwellTimeMicroSeconds  matlab.ui.control.NumericEditField
        RepeatsLabel_6                 matlab.ui.control.Label
        edit_RasterScan_PixelStepSize  matlab.ui.control.NumericEditField
        PixelStepsizeVLabel            matlab.ui.control.Label
        edit_RasterScan_DwellTime      matlab.ui.control.NumericEditField
        RepeatsLabel_5                 matlab.ui.control.Label
        edit_RasterScan_Rate           matlab.ui.control.NumericEditField
        RepeatsLabel_4                 matlab.ui.control.Label
        edit_RasterScan_repeats        matlab.ui.control.NumericEditField
        RepeatsLabel_3                 matlab.ui.control.Label
        button_stopRasterScan          matlab.ui.control.Button
        button_startRasterScan         matlab.ui.control.Button
        edit_RasterScan_AmplitudeY     matlab.ui.control.NumericEditField
        AmplitudeYEditFieldLabel_2     matlab.ui.control.Label
        edit_RasterScan_AmplitudeX     matlab.ui.control.NumericEditField
        AmplitudeXEditFieldLabel_2     matlab.ui.control.Label
        SetupTab                       matlab.ui.container.Tab
        GalvoinitializedLamp           matlab.ui.control.Lamp
        GalvoinitializedLampLabel      matlab.ui.control.Label
        InitGalvosButton               matlab.ui.control.Button
        edit_deviceName                matlab.ui.control.EditField
        DeviceNameEditFieldLabel       matlab.ui.control.Label
        GalvoBusyLamp                  matlab.ui.control.Lamp
        GalvoBusyLampLabel             matlab.ui.control.Label
    end

    
    properties (Access = public)
        VoltageX = 0; % Description
        VoltageY = 0;
        GalvoHandle;
        GalvoInit;
    end
    
    methods (Access = private)
        
        function changeVoltageStatic(app,voltage_x,voltage_y)
            app.CurrentVoltageXEditField.Value = voltage_x;
            app.CurrentVoltageYEditField.Value = voltage_y;
            app.VoltageX = voltage_x;
            app.VoltageY = voltage_y;
            write(app.GalvoHandle,[voltage_x, voltage_y]);
        end
        
        function PlotWaveform(app)
            amplitudeX = app.VoltageX_Amplitude.Value;
            amplitudeY = app.VoltageY_Amplitude.Value;
            Voltage_x = app.VoltageX_Static.Value;
            Voltage_y = app.VoltageY_Static.Value;
            switch app.LineScanMode.SelectedObject.Text
                case 'Along X'
                    data_x = linspace(amplitudeX, -amplitudeX, 200);
                    data_x = [data_x,flip(data_x)];
                    x_plot = data_x;
                    y_plot = repmat(Voltage_y,1,length(data_x));
                case 'Along Y'
                    data_y = linspace(amplitudeY, -amplitudeY, 200);
                    data_y = [data_y,flip(data_y)];
                    x_plot = repmat(Voltage_x,1,length(data_y));
                    y_plot = data_y;
                case 'Alternating'
                    data_x1 = linspace(amplitudeX, -amplitudeX, 200);
                    data_x1 = [data_x1,flip(data_x1)];
                    data_x1 = repmat(data_x1,1,20);
                    data_x2 = repmat(Voltage_x,1,length(data_x1));
                    data_x = [data_x1,data_x2];
                    data_y1 = linspace(amplitudeY, -amplitudeY, 200);
                    data_y1 = [data_y1,flip(data_y1)];
                    data_y1 = repmat(data_y1,1,20);
                    data_y2 = repmat(Voltage_y,1,length(data_y1));
                    data_y = [data_y2,data_y1];
                    data_x2 = repmat(data_x,1,40);
                    data_y2 = repmat(data_y,1,40);
                    x_plot = data_x2(1:16000);
                    y_plot = data_y2(1:16000);
                case 'Circle'
                    points = 1000;
                    voltages = zeros(points,2);
                    for p = 1:points
                        voltages(p,1) = amplitudeX*sin(2*p*pi/points);
                        voltages(p,2) = amplitudeY*cos(2*p*pi/points);
                    end
                    x_plot = voltages(:,1);
                    y_plot = voltages(:,2);
            end
            % Preview Waveform 
            app.UIAxes.NextPlot = 'replace';

            plot(app.UIAxes,x_plot,'LineWidth',1.5,'Color','r');
            app.UIAxes.NextPlot = 'add';
            plot(app.UIAxes,y_plot,'LineWidth',1.5,'Color','b');
%             app.UIAxes.XLabel = 'Time';
%             app.UIAxes.YLabel = 'Voltage';
        end
        
        function calculateRasterScanProps(app)
            DwellTimeMS = app.edit_RasterScan_DwellTime.Value;
            DwellTimeS = DwellTimeMS/1000;
            amplitudeX = app.edit_RasterScan_AmplitudeX.Value;
            amplitudeY = app.edit_RasterScan_AmplitudeY.Value;
            VoltageStep = app.edit_RasterScan_PixelStepSize.Value;
            data_x1 = -amplitudeX:VoltageStep:amplitudeX;
            data_y1 = -amplitudeY:VoltageStep:amplitudeY;


            NScanPoints = length(data_y1) *length(data_x1);
            Time = NScanPoints*DwellTimeS;
            app.edit_RasterScan_ScanTime.Value = Time;
            app.edit_RasterScan_ScanPoints.Value = NScanPoints;
            % Calculate Repeats Necessary and make it read only
            app.GalvoHandle.Rate = app.edit_RasterScan_Rate.Value;
            NumDatapointsRequired = double(app.GalvoHandle.ScansRequiredFcnCount);
            app.edit_RasterScan_repeats.Value = 2*ceil(NumDatapointsRequired/NScanPoints);


        end
    end
    
    methods (Access = public)
        
        function data = updateWaveform(app)
            app.GalvoHandle.Rate = app.GalvoRate.Value;
            repeats  = app.Repeats.Value;
            amplitudeX = app.VoltageX_Amplitude.Value;
            amplitudeY = app.VoltageY_Amplitude.Value;
            Voltage_x = app.VoltageX_Static.Value;
            Voltage_y = app.VoltageY_Static.Value;
            
            waveform = app.dropdown_waveform.Value;
            app.GalvoHandle.Rate = app.GalvoRate.Value;

            switch app.LineScanMode.SelectedObject.Text
                case 'Along X'
                    switch waveform
                        case 'Sine'
                            points = 200;
                            data_x = zeros(1,points);
                            for p = 1:points
                                data_x(1,p) = amplitudeX*sin(2*p*pi/points);
                            end
                            data_x = [data_x, flipud(data_x)];

                        case 'Triangle'
                            data_x = linspace(amplitudeX, -amplitudeX, 200);
                            data_x = [data_x, flip(data_x)];

                    end
                    data_y_plot = repmat(Voltage_y,1,length(data_x));
            
                    app.UIAxes.NextPlot = 'replace';
                    plot(app.UIAxes,data_x,'LineWidth',1.5,'Color','r');
                    app.UIAxes.NextPlot = 'add';
                    plot(app.UIAxes,data_y_plot,'LineWidth',1.5,'Color','b');
            
                    data_x = repmat(data_x,1,repeats);
                    data_y = repmat(Voltage_y,1,length(data_x));
                    data = [data_x;data_y]';
            
                case 'Along Y'
                    switch waveform
                        case 'Sine'
                            points = 200;
                            data_y = zeros(1,points);
                            for p = 1:points
                                data_y(1,p) = amplitudeY*sin(2*p*pi/points);
                            end
                            data_y = [data_y, flipud(data_y)];

                        case 'Triangle'
                            data_y = linspace(amplitudeY, -amplitudeY, 200);
                            data_y = [data_y, flip(data_y)];

                    end
                    data_x_plot = repmat(Voltage_x,1,length(data_y));
            
                    app.UIAxes.NextPlot = 'replace';
                    plot(app.UIAxes,data_x_plot,'LineWidth',1.5,'Color','r');
                    app.UIAxes.NextPlot = 'add';
                    plot(app.UIAxes,data_y,'LineWidth',1.5,'Color','b');
            
                    data_y = repmat(data_y,1,repeats);
                    data_x = repmat(Voltage_x,1,length(data_y));
                    data = [data_x;data_y]';
            
                case 'Alternating'
                    switch waveform
                        case 'Sine'
                            points = 200;
                            data_x1 = zeros(1,points);
                            data_y1 = zeros(1,points);
                            for p = 1:points
                                data_x1(1,p) = amplitudeX*sin(2*p*pi/points);
                                data_y1(1,p) = amplitudeY*sin(2*p*pi/points);
                            end
                        case 'Triangle'
                            data_x1 = linspace(amplitudeX, -amplitudeX, 200);
                            data_y1 = linspace(amplitudeY, -amplitudeY, 200);
                    end
            
                    app.UIAxes.NextPlot = 'replace';
                    plot(app.UIAxes,data_x1,'LineWidth',1.5,'Color','r');
                    app.UIAxes.NextPlot = 'add';
                    plot(app.UIAxes,data_y1,'LineWidth',1.5,'Color','b');
            
                    data_x1 = [data_x1, flip(data_x1)];
                    data_x2 = repmat(Voltage_x,1,length(data_x1));
                    data_x  = [data_x1, data_x2];
            
                    data_y1 = [data_y1, flip(data_y1)];
                    data_y2 = repmat(Voltage_y,1,length(data_y1));
                    data_y  = [data_y2, data_y1];
            
                    data_x = repmat(data_x,1,repeats);
                    data_y = repmat(data_y,1,repeats);
                    data = [data_x;data_y]';
            
                case 'Circle'
                    points = 1000;
                    data_x1 = zeros(1,points);
                    data_y1 = zeros(1,points);
                    for p = 1:points
                        data_x1(1,p) = amplitudeX*sin(2*p*pi/points);
                        data_y1(1,p) = amplitudeY*cos(2*p*pi/points);
                    end
            
                    app.UIAxes.NextPlot = 'replace';
                    plot(app.UIAxes,data_x1,'LineWidth',1.5,'Color','r');
                    app.UIAxes.NextPlot = 'add';
                    plot(app.UIAxes,data_y1,'LineWidth',1.5,'Color','b');
            
                    voltages = [data_x1; data_y1]';
                    data = repmat(voltages,repeats,1);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Import Galvo from handles
            app.GalvoControlPanelUIFigure.WindowStyle = 'alwaysontop';
            app.GalvoInit = 0;
            try
                app.GalvoInit = evalin('base','handles.settings.galvo.init');
                app.GalvoHandle = evalin('base','handles.settings.galvo.handle');
            end
            if app.GalvoInit
                app.GalvoinitializedLamp.Color = 'green';
                app.InitGalvosButton.Enable = 'off';
                calculateRasterScanProps(app)

            end

        end

        % Button pushed function: VoltageX_increaseSmall
        function VoltageX_increaseSmallPushed(app, event)
            voltageX = app.VoltageX + 0.01;
            voltageY = app.VoltageY;
            changeVoltageStatic(app,voltageX,voltageY);
        end

        % Button pushed function: VoltageX_dereaseMedium
        function VoltageX_dereaseMediumButtonPushed(app, event)
            voltageX = app.VoltageX - 0.1;
            voltageY = app.VoltageY;
            changeVoltageStatic(app,voltageX,voltageY);
        end

        % Button pushed function: VoltageX_dereaseLarge
        function VoltageX_dereaseLargeButtonPushed(app, event)
            voltageX = app.VoltageX - 1;
            voltageY = app.VoltageY;
            changeVoltageStatic(app,voltageX,voltageY);
        end

        % Button pushed function: VoltageX_dereaseSmall
        function VoltageX_dereaseSmallButtonPushed(app, event)
            voltageX = app.VoltageX - 0.01;
            voltageY = app.VoltageY;
            changeVoltageStatic(app,voltageX,voltageY);
        end

        % Button pushed function: VoltageX_increaseLarge
        function VoltageX_increaseLargeButtonPushed(app, event)
            voltageX = app.VoltageX + 1;
            voltageY = app.VoltageY;
            changeVoltageStatic(app,voltageX,voltageY);
        end

        % Button pushed function: VoltageX_increaseMedium
        function VoltageX_increaseMediumButtonPushed(app, event)
            voltageX = app.VoltageX + 0.1;
            voltageY = app.VoltageY;
            changeVoltageStatic(app,voltageX,voltageY);
        end

        % Button pushed function: VoltageY_increaseSmall
        function VoltageY_increaseSmallButtonPushed(app, event)
            voltageX = app.VoltageX;
            voltageY = app.VoltageY + 0.01;
            changeVoltageStatic(app,voltageX,voltageY);
        end

        % Button pushed function: VoltageY_increaseMedium
        function VoltageY_increaseMediumButtonPushed(app, event)
            voltageX = app.VoltageX;
            voltageY = app.VoltageY + 0.1;
            changeVoltageStatic(app,voltageX,voltageY);
        end

        % Button pushed function: VoltageY_increaseLarge
        function VoltageY_increaseLargeButtonPushed(app, event)
            voltageX = app.VoltageX;
            voltageY = app.VoltageY + 1;
            changeVoltageStatic(app,voltageX,voltageY);
        end

        % Button pushed function: VoltageY_decreaseSmall
        function VoltageY_decreaseSmallButtonPushed(app, event)
            voltageX = app.VoltageX;
            voltageY = app.VoltageY - 0.01;
            changeVoltageStatic(app,voltageX,voltageY);
        end

        % Button pushed function: VoltageY_decreaseLarge
        function VoltageY_decreaseLargeButtonPushed(app, event)
            voltageX = app.VoltageX;
            voltageY = app.VoltageY - 1;
            changeVoltageStatic(app,voltageX,voltageY);
        end

        % Button pushed function: VoltageY_decreaseMedium
        function VoltageY_decreaseMediumButtonPushed(app, event)
            voltageX = app.VoltageX;
            voltageY = app.VoltageY - 0.1;
            changeVoltageStatic(app,voltageX,voltageY);
        end

        % Button pushed function: InitGalvosButton
        function InitGalvosButtonPushed(app, event)
            devices = daqlist;
            app.GalvoHandle = daq('ni');
            % Add output Channels for x and y Galvo
            galvo_x = addoutput(app.GalvoHandle,app.edit_deviceName.Value,'ao0','Voltage');
            galvo_y = addoutput(app.GalvoHandle,app.edit_deviceName.Value,'ao1','Voltage');
            app.GalvoInit = 1;
            disp('Galvos are now initialized');
            write(app.GalvoHandle, [0 0]);
            % send handle to base
            try
                assignin('base',handles.settings.galvo.handle,'app.GalvoHandle')
                assignin('base',handles.settings.galvo.init,'app.GalvoInit')
            catch
                warning('Failed to assign Galvo handle to Biofilm Toolbox Handles')
            end
            app.InitGalvosButton.Enable = 'Off';
            app.GalvoinitializedLamp.Color = 'green';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            data = updateWaveform(app);
            preload(app.GalvoHandle,data) 
            start(app.GalvoHandle,'RepeatOutput');
            % start(app.GalvoHandle);
            app.GalvoBusyLamp.Color = 'y';
            app.StartButton.Enable  =  'off';

        end

        % Value changed function: VoltageX_Amplitude
        function VoltageX_AmplitudeValueChanged(app, event)
            % PlotWaveform(app)
            updateWaveform(app)
        end

        % Value changed function: VoltageY_Amplitude
        function VoltageY_AmplitudeValueChanged(app, event)
            % PlotWaveform(app)
            updateWaveform(app)
        end

        % Value changed function: VoltageX_Static
        function VoltageX_StaticValueChanged(app, event)
            % PlotWaveform(app)
            updateWaveform(app)
        end

        % Value changed function: VoltageY_Static
        function VoltageY_StaticValueChanged(app, event)
            % PlotWaveform(app)
            updateWaveform(app)
        end

        % Selection changed function: LineScanMode
        function LineScanModeSelectionChanged(app, event)
            % PlotWaveform(app)
            updateWaveform(app)
        end

        % Button pushed function: StopButton, button_stopRasterScan
        function StopButtonPushed(app, event)
            app.GalvoHandle.stop;
            app.GalvoBusyLamp.Color = 'g';
            app.StartButton.Enable  =  'on';
            app.button_startRasterScan.Enable =  'on';
            changeVoltageStatic(app,0,0)
        end

        % Close request function: GalvoControlPanelUIFigure
        function GalvoControlPanelUIFigureCloseRequest(app, event)
            disp('DAQ Reset')
            if ~isempty(app.GalvoHandle) && isvalid(app.GalvoHandle)
                stop(app.GalvoHandle);
                write(app.GalvoHandle, [0 0]);  % return to centre before releasing
                delete(app.GalvoHandle);
            end
            delete(app)
            
        end

        % Button pushed function: Setto00Button, VoltageY_decreaseSmall_2
        function Setto00ButtonPushed(app, event)
            app.GalvoHandle.stop;
            changeVoltageStatic(app,0,0);
        end

        % Button pushed function: button_startRasterScan
        function button_startRasterScanPushed(app, event)
            repeats  = app.edit_RasterScan_repeats.Value;
            app.GalvoHandle.Rate = app.edit_RasterScan_Rate.Value;
            
            amplitudeX = app.edit_RasterScan_AmplitudeX.Value;
            amplitudeY = app.edit_RasterScan_AmplitudeY.Value;
            offsetX = app.edit_RasterScan_OffsetX.Value;
            offsetY = app.edit_RasterScan_OffsetY.Value;
            VoltageStep = app.edit_RasterScan_PixelStepSize.Value;
            scanAxis = app.dropdown_RasterScan_FastAxis.Value;
            waveform = app.dropdown_RasterScan_Waveform.Value;
            
            % Calculate Voltage Arrays for Bidirectional Scanning
            switch scanAxis
                case 'X'
                    fastRange = -amplitudeX+offsetX:VoltageStep:amplitudeX+offsetX;
                    switch waveform
                        case 'Triangle'
                            data_x1 = fastRange;
                        case 'Sine'
                            points = length(fastRange);
                            data_x1 = zeros(1,points);
                            for p = 1:points
                                data_x1(1,p) = amplitudeX*sin(2*p*pi/points) + offsetX;
                            end
                    end
                    data_y1 = -amplitudeY+offsetY:VoltageStep:amplitudeY+offsetY;
                case 'Y'
                    fastRange = -amplitudeY+offsetY:VoltageStep:amplitudeY+offsetY;
                    switch waveform
                        case 'Triangle'
                            data_x1 = fastRange;
                        case 'Sine'
                            points = length(fastRange);
                            data_x1 = zeros(1,points);
                            for p = 1:points
                                data_x1(1,p) = amplitudeY*sin(2*p*pi/points) + offsetY;
                            end
                    end
                    data_y1 = -amplitudeX+offsetX:VoltageStep:amplitudeX+offsetX;
            end
            
            ScanRowsY = length(data_y1);
            ScanRowsX = length(data_x1);
            data_y1 = repelem(data_y1,length(data_x1));
            data_x1 = [data_x1,flip(data_x1)];
            if mod(ScanRowsY,2)
                data_x1 =  repmat(data_x1,1,(1+ScanRowsY)*0.5);
                data_x1 = data_x1(1:end-ScanRowsX); 
            else
                data_x1 =  repmat(data_x1,1,ScanRowsY*0.5);
            end
            NumDatapointsRequired = double(app.GalvoHandle.ScansRequiredFcnCount);
            if length(data_x1) < NumDatapointsRequired
                repeatsNecessary = ceil(NumDatapointsRequired/length(data_x1));
                data_x2 = repmat(data_x1,1,repeatsNecessary);
                data_y2 = repmat(data_y1,1,repeatsNecessary);
                data = [data_x2;data_y2]';
            else
                data = [data_x1;data_y1]';
            end
                
            preload(app.GalvoHandle,data) 
            start(app.GalvoHandle,'RepeatOutput');
            app.GalvoBusyLamp.Color = 'y';
            app.button_startRasterScan.Enable  =  'off';
          
        end

        % Value changed function: edit_RasterScan_Rate
        function edit_RasterScan_RateValueChanged(app, event)
            ScanRate = app.edit_RasterScan_Rate.Value;
            DwellTime = 1/ScanRate;
            app.edit_RasterScan_DwellTime.Value = DwellTime*1000;
            app.edit_RasterScan_DwellTimeMicroSeconds.Value = DwellTime*1000*1000;
            calculateRasterScanProps(app)
        end

        % Value changed function: edit_RasterScan_DwellTime
        function edit_RasterScan_DwellTimeValueChanged(app, event)
            DwellTime = app.edit_RasterScan_DwellTime.Value/1000;
            ScanRate = 1/DwellTime;
            app.edit_RasterScan_Rate.Value = ScanRate;
            app.edit_RasterScan_DwellTimeMicroSeconds.Value = app.edit_RasterScan_DwellTime.Value*1000;
            calculateRasterScanProps(app)
        end

        % Value changed function: edit_RasterScan_DwellTimeMicroSeconds
        function edit_RasterScan_DwellTimeMicroSecondsValueChanged(app, event)
            DwellTimeMicroSeconds = app.edit_RasterScan_DwellTimeMicroSeconds.Value;
            DwellTimeMS = DwellTimeMicroSeconds/1000;
            DwellTimeS = DwellTimeMS/1000;
            app.edit_RasterScan_DwellTime.Value = DwellTimeMS;
            ScanRate = 1/DwellTimeS;
            app.edit_RasterScan_Rate.Value = ScanRate;
            calculateRasterScanProps(app)
        end

        % Value changed function: edit_RasterScan_ScanTime
        function edit_RasterScan_ScanTimeValueChanged(app, event)
            value = app.edit_RasterScan_ScanTime.Value;
            
        end

        % Value changed function: edit_RasterScan_AmplitudeX
        function edit_RasterScan_AmplitudeXValueChanged(app, event)
            calculateRasterScanProps(app)
        end

        % Value changed function: edit_RasterScan_AmplitudeY
        function edit_RasterScan_AmplitudeYValueChanged(app, event)
            calculateRasterScanProps(app)

        end

        % Value changed function: edit_RasterScan_PixelStepSize
        function edit_RasterScan_PixelStepSizeValueChanged(app, event)
            value = app.edit_RasterScan_PixelStepSize.Value;
            calculateRasterScanProps(app)
        end

        % Value changed function: edit_RasterScan_ScanPoints
        function edit_RasterScan_ScanPointsValueChanged(app, event)
            value = app.edit_RasterScan_ScanPoints.Value;
            
        end

        % Menu selected function: menu_assignWorkspace
        function menu_assignWorkspaceSelected(app, event)
            assignin('base','app',app)
        end

        % Menu selected function: menu_about
        function menu_aboutMenuSelected(app, event)
            uiconfirm(app.GalvoControlPanelUIFigure,'Galvo control developed by Konstantin Neuhaus (konneuhaus@gmail.com)','About','Icon','info','Options',{'OK'})

        end

        % Menu selected function: menu_disconnect
        function menu_disconnectSelected(app, event)
            stop(app.GalvoHandle);
            write(app.GalvoHandle, [0 0]);  % return to centre before releasing
            delete(app.GalvoHandle);
        end

        % Value changed function: dropdown_waveform
        function dropdown_waveformValueChanged(app, event)
            % value = app.dropdown_waveform.Value;
            updateWaveform(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GalvoControlPanelUIFigure = uifigure('Visible', 'off');
            app.GalvoControlPanelUIFigure.Name = 'Galvo Control Panel';
            app.GalvoControlPanelUIFigure.Icon = fullfile(pathToMLAPP, 'icon_galvo_use.png');
            app.GalvoControlPanelUIFigure.CloseRequestFcn = createCallbackFcn(app, @GalvoControlPanelUIFigureCloseRequest, true);
            app.Menu = uimenu(app.GalvoControlPanelUIFigure);
            app.Menu.Text = 'Menu';
            app.menu_reconnect = uimenu(app.Menu);
            app.menu_reconnect.Text = 'Reconnect';
            app.menu_disconnect = uimenu(app.Menu);
            app.menu_disconnect.MenuSelectedFcn = createCallbackFcn(app, @menu_disconnectSelected, true);
            app.menu_disconnect.Text = 'disconnect';
            app.menu_debug = uimenu(app.Menu);
            app.menu_debug.Text = 'Debug';
            app.menu_assignWorkspace = uimenu(app.Menu);
            app.menu_assignWorkspace.MenuSelectedFcn = createCallbackFcn(app, @menu_assignWorkspaceSelected, true);
            app.menu_assignWorkspace.Text = 'Assign to workspace';
            app.menu_about = uimenu(app.Menu);
            app.menu_about.MenuSelectedFcn = createCallbackFcn(app, @menu_aboutMenuSelected, true);
            app.menu_about.Text = 'About';
            app.GalvoBusyLampLabel = uilabel(app.GalvoControlPanelUIFigure);
            app.GalvoBusyLampLabel.Text = 'Galvo Busy';
            app.GalvoBusyLamp = uilamp(app.GalvoControlPanelUIFigure);
            app.TabGroup = uitabgroup(app.GalvoControlPanelUIFigure);
            app.StaticTab = uitab(app.TabGroup);
            app.StaticTab.Title = 'Static';
            app.CurrentVoltageXEditFieldLabel = uilabel(app.StaticTab);
            app.CurrentVoltageXEditFieldLabel.Text = 'Current Voltage X';
            app.CurrentVoltageXEditField = uieditfield(app.StaticTab, 'numeric');
            app.CurrentVoltageXEditField.Limits = [-10 10];
            app.CurrentVoltageYEditFieldLabel = uilabel(app.StaticTab);
            app.CurrentVoltageYEditFieldLabel.Text = 'Current Voltage Y';
            app.CurrentVoltageYEditField = uieditfield(app.StaticTab, 'numeric');
            app.CurrentVoltageYEditField.Limits = [-10 10];
            app.VoltageY_increaseSmall = uibutton(app.StaticTab, 'push');
            app.VoltageY_increaseSmall.ButtonPushedFcn = createCallbackFcn(app, @VoltageY_increaseSmallButtonPushed, true);
            app.VoltageY_increaseSmall.Text = '+0.01';
            app.VoltageY_increaseMedium = uibutton(app.StaticTab, 'push');
            app.VoltageY_increaseMedium.ButtonPushedFcn = createCallbackFcn(app, @VoltageY_increaseMediumButtonPushed, true);
            app.VoltageY_increaseMedium.Text = '+0.1';
            app.VoltageY_increaseLarge = uibutton(app.StaticTab, 'push');
            app.VoltageY_increaseLarge.ButtonPushedFcn = createCallbackFcn(app, @VoltageY_increaseLargeButtonPushed, true);
            app.VoltageY_increaseLarge.Text = '+1';
            app.VoltageY_decreaseSmall = uibutton(app.StaticTab, 'push');
            app.VoltageY_decreaseSmall.ButtonPushedFcn = createCallbackFcn(app, @VoltageY_decreaseSmallButtonPushed, true);
            app.VoltageY_decreaseSmall.Text = '-0.01';
            app.VoltageY_decreaseLarge = uibutton(app.StaticTab, 'push');
            app.VoltageY_decreaseLarge.ButtonPushedFcn = createCallbackFcn(app, @VoltageY_decreaseLargeButtonPushed, true);
            app.VoltageY_decreaseLarge.Text = '-1';
            app.VoltageY_decreaseMedium = uibutton(app.StaticTab, 'push');
            app.VoltageY_decreaseMedium.ButtonPushedFcn = createCallbackFcn(app, @VoltageY_decreaseMediumButtonPushed, true);
            app.VoltageY_decreaseMedium.Text = '-0.1';
            app.VoltageX_increaseSmall = uibutton(app.StaticTab, 'push');
            app.VoltageX_increaseSmall.ButtonPushedFcn = createCallbackFcn(app, @VoltageX_increaseSmallPushed, true);
            app.VoltageX_increaseSmall.Text = '+0.01';
            app.VoltageX_increaseMedium = uibutton(app.StaticTab, 'push');
            app.VoltageX_increaseMedium.ButtonPushedFcn = createCallbackFcn(app, @VoltageX_increaseMediumButtonPushed, true);
            app.VoltageX_increaseMedium.Text = '+0.1';
            app.VoltageX_increaseLarge = uibutton(app.StaticTab, 'push');
            app.VoltageX_increaseLarge.ButtonPushedFcn = createCallbackFcn(app, @VoltageX_increaseLargeButtonPushed, true);
            app.VoltageX_increaseLarge.Text = '+1';
            app.VoltageX_dereaseSmall = uibutton(app.StaticTab, 'push');
            app.VoltageX_dereaseSmall.ButtonPushedFcn = createCallbackFcn(app, @VoltageX_dereaseSmallButtonPushed, true);
            app.VoltageX_dereaseSmall.Text = '-0.01';
            app.VoltageX_dereaseLarge = uibutton(app.StaticTab, 'push');
            app.VoltageX_dereaseLarge.ButtonPushedFcn = createCallbackFcn(app, @VoltageX_dereaseLargeButtonPushed, true);
            app.VoltageX_dereaseLarge.Text = '-1';
            app.VoltageX_dereaseMedium = uibutton(app.StaticTab, 'push');
            app.VoltageX_dereaseMedium.ButtonPushedFcn = createCallbackFcn(app, @VoltageX_dereaseMediumButtonPushed, true);
            app.VoltageX_dereaseMedium.Text = '-0.1';
            app.Setto00Button = uibutton(app.StaticTab, 'push');
            app.Setto00Button.ButtonPushedFcn = createCallbackFcn(app, @Setto00ButtonPushed, true);
            app.Setto00Button.Text = 'Set to (0,0)';
            app.VoltageY_decreaseSmall_2 = uibutton(app.StaticTab, 'push');
            app.VoltageY_decreaseSmall_2.ButtonPushedFcn = createCallbackFcn(app, @Setto00ButtonPushed, true);
            app.VoltageY_decreaseSmall_2.Text = '0,0';
            app.DynamicTab = uitab(app.TabGroup);
            app.DynamicTab.Title = 'Dynamic';
            app.UIAxes = uiaxes(app.DynamicTab);
            title(app.UIAxes, 'Waveform Preview')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Voltage')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.DynamicTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.DynamicTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.AmplitudeXEditFieldLabel = uilabel(app.DynamicTab);
            app.AmplitudeXEditFieldLabel.Text = 'Amplitude X';
            app.VoltageX_Amplitude = uieditfield(app.DynamicTab, 'numeric');
            app.VoltageX_Amplitude.Limits = [-10 10];
            app.VoltageX_Amplitude.ValueChangedFcn = createCallbackFcn(app, @VoltageX_AmplitudeValueChanged, true);
            app.VoltageX_Amplitude.Value = 3;
            app.AmplitudeYEditFieldLabel = uilabel(app.DynamicTab);
            app.AmplitudeYEditFieldLabel.Text = 'Amplitude Y';
            app.VoltageY_Amplitude = uieditfield(app.DynamicTab, 'numeric');
            app.VoltageY_Amplitude.Limits = [-10 10];
            app.VoltageY_Amplitude.ValueChangedFcn = createCallbackFcn(app, @VoltageY_AmplitudeValueChanged, true);
            app.VoltageY_Amplitude.Value = 3;
            app.StaticXEditFieldLabel = uilabel(app.DynamicTab);
            app.StaticXEditFieldLabel.Text = 'Static X';
            app.VoltageX_Static = uieditfield(app.DynamicTab, 'numeric');
            app.VoltageX_Static.Limits = [-10 10];
            app.VoltageX_Static.ValueChangedFcn = createCallbackFcn(app, @VoltageX_StaticValueChanged, true);
            app.StaticYEditFieldLabel = uilabel(app.DynamicTab);
            app.StaticYEditFieldLabel.Text = 'Static Y';
            app.VoltageY_Static = uieditfield(app.DynamicTab, 'numeric');
            app.VoltageY_Static.Limits = [-10 10];
            app.VoltageY_Static.ValueChangedFcn = createCallbackFcn(app, @VoltageY_StaticValueChanged, true);
            app.LineScanMode = uibuttongroup(app.DynamicTab);
            app.LineScanMode.SelectionChangedFcn = createCallbackFcn(app, @LineScanModeSelectionChanged, true);
            app.LineScanMode.Title = 'Line Scan Mode';
            app.AlongX = uitogglebutton(app.LineScanMode);
            app.AlongX.Text = 'Along X';
            app.AlongX.Value = true;
            app.AlongY = uitogglebutton(app.LineScanMode);
            app.AlongY.Text = 'Along Y';
            app.Alternating = uitogglebutton(app.LineScanMode);
            app.Alternating.Text = 'Alternating';
            app.Alternating_2 = uitogglebutton(app.LineScanMode);
            app.Alternating_2.Text = 'Circle';
            app.RepeatsLabel = uilabel(app.DynamicTab);
            app.RepeatsLabel.Text = 'Repeats';
            app.Repeats = uieditfield(app.DynamicTab, 'numeric');
            app.Repeats.Limits = [1 1000];
            app.Repeats.Value = 50;
            app.RepeatsLabel_2 = uilabel(app.DynamicTab);
            app.RepeatsLabel_2.Text = 'Galvo Rate';
            app.GalvoRate = uieditfield(app.DynamicTab, 'numeric');
            app.GalvoRate.Limits = [0.1 1000000];
            app.GalvoRate.ValueDisplayFormat = '%.0f';
            app.GalvoRate.Value = 20000;
            app.WaveformDropDownLabel = uilabel(app.DynamicTab);
            app.WaveformDropDownLabel.Text = 'Waveform';
            app.dropdown_waveform = uidropdown(app.DynamicTab);
            app.dropdown_waveform.Items = {'Sine', 'Triangle'};
            app.dropdown_waveform.ValueChangedFcn = createCallbackFcn(app, @dropdown_waveformValueChanged, true);
            app.dropdown_waveform.Value = 'Sine';
            app.RasterScanTab = uitab(app.TabGroup);
            app.RasterScanTab.Title = 'Raster Scan';
            app.AmplitudeXEditFieldLabel_2 = uilabel(app.RasterScanTab);
            app.AmplitudeXEditFieldLabel_2.Text = 'Amplitude X';
            app.edit_RasterScan_AmplitudeX = uieditfield(app.RasterScanTab, 'numeric');
            app.edit_RasterScan_AmplitudeX.Limits = [-10 10];
            app.edit_RasterScan_AmplitudeX.ValueChangedFcn = createCallbackFcn(app, @edit_RasterScan_AmplitudeXValueChanged, true);
            app.edit_RasterScan_AmplitudeX.Value = 0.3;
            app.AmplitudeYEditFieldLabel_2 = uilabel(app.RasterScanTab);
            app.AmplitudeYEditFieldLabel_2.Text = 'Amplitude Y';
            app.edit_RasterScan_AmplitudeY = uieditfield(app.RasterScanTab, 'numeric');
            app.edit_RasterScan_AmplitudeY.Limits = [-10 10];
            app.edit_RasterScan_AmplitudeY.ValueChangedFcn = createCallbackFcn(app, @edit_RasterScan_AmplitudeYValueChanged, true);
            app.edit_RasterScan_AmplitudeY.Value = 0.3;
            app.button_startRasterScan = uibutton(app.RasterScanTab, 'push');
            app.button_startRasterScan.ButtonPushedFcn = createCallbackFcn(app, @button_startRasterScanPushed, true);
            app.button_startRasterScan.Text = 'Start';
            app.button_stopRasterScan = uibutton(app.RasterScanTab, 'push');
            app.button_stopRasterScan.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.button_stopRasterScan.Text = 'Stop';
            app.RepeatsLabel_3 = uilabel(app.RasterScanTab);
            app.RepeatsLabel_3.Text = 'Repeats';
            app.edit_RasterScan_repeats = uieditfield(app.RasterScanTab, 'numeric');
            app.edit_RasterScan_repeats.Limits = [1 5000];
            app.edit_RasterScan_repeats.Value = 2;
            app.RepeatsLabel_4 = uilabel(app.RasterScanTab);
            app.RepeatsLabel_4.Text = 'Galvo Rate [Hz]';
            app.edit_RasterScan_Rate = uieditfield(app.RasterScanTab, 'numeric');
            app.edit_RasterScan_Rate.Limits = [0.1 1000000];
            app.edit_RasterScan_Rate.ValueDisplayFormat = '%.1f';
            app.edit_RasterScan_Rate.ValueChangedFcn = createCallbackFcn(app, @edit_RasterScan_RateValueChanged, true);
            app.edit_RasterScan_Rate.Value = 1000;
            app.RepeatsLabel_5 = uilabel(app.RasterScanTab);
            app.RepeatsLabel_5.Text = 'Dwell Time [ms] ';
            app.edit_RasterScan_DwellTime = uieditfield(app.RasterScanTab, 'numeric');
            app.edit_RasterScan_DwellTime.LowerLimitInclusive = 'off';
            app.edit_RasterScan_DwellTime.Limits = [0 10000000];
            app.edit_RasterScan_DwellTime.ValueDisplayFormat = '%5.2f';
            app.edit_RasterScan_DwellTime.ValueChangedFcn = createCallbackFcn(app, @edit_RasterScan_DwellTimeValueChanged, true);
            app.edit_RasterScan_DwellTime.Value = 0.1;
            app.PixelStepsizeVLabel = uilabel(app.RasterScanTab);
            app.PixelStepsizeVLabel.Text = 'Pixel Step size [V]';
            app.edit_RasterScan_PixelStepSize = uieditfield(app.RasterScanTab, 'numeric');
            app.edit_RasterScan_PixelStepSize.Limits = [-10 10];
            app.edit_RasterScan_PixelStepSize.ValueChangedFcn = createCallbackFcn(app, @edit_RasterScan_PixelStepSizeValueChanged, true);
            app.edit_RasterScan_PixelStepSize.Value = 0.01;
            app.RepeatsLabel_6 = uilabel(app.RasterScanTab);
            app.RepeatsLabel_6.Text = 'Dwell Time [us] ';
            app.edit_RasterScan_DwellTimeMicroSeconds = uieditfield(app.RasterScanTab, 'numeric');
            app.edit_RasterScan_DwellTimeMicroSeconds.LowerLimitInclusive = 'off';
            app.edit_RasterScan_DwellTimeMicroSeconds.Limits = [0 100000000000];
            app.edit_RasterScan_DwellTimeMicroSeconds.ValueDisplayFormat = '%5.3f';
            app.edit_RasterScan_DwellTimeMicroSeconds.ValueChangedFcn = createCallbackFcn(app, @edit_RasterScan_DwellTimeMicroSecondsValueChanged, true);
            app.edit_RasterScan_DwellTimeMicroSeconds.Value = 100;
            app.RepeatsLabel_7 = uilabel(app.RasterScanTab);
            app.RepeatsLabel_7.Text = 'Total Time per Field [s]';
            app.edit_RasterScan_ScanTime = uieditfield(app.RasterScanTab, 'numeric');
            app.edit_RasterScan_ScanTime.LowerLimitInclusive = 'off';
            app.edit_RasterScan_ScanTime.Limits = [0 100000000000];
            app.edit_RasterScan_ScanTime.ValueDisplayFormat = '%5.3f';
            app.edit_RasterScan_ScanTime.ValueChangedFcn = createCallbackFcn(app, @edit_RasterScan_ScanTimeValueChanged, true);
            app.edit_RasterScan_ScanTime.Editable = 'off';
            app.edit_RasterScan_ScanTime.Value = 0.37;
            app.PixelStepsizeVLabel_2 = uilabel(app.RasterScanTab);
            app.PixelStepsizeVLabel_2.Text = 'Scan Points';
            app.edit_RasterScan_ScanPoints = uieditfield(app.RasterScanTab, 'numeric');
            app.edit_RasterScan_ScanPoints.Limits = [0 1000000000000];
            app.edit_RasterScan_ScanPoints.ValueChangedFcn = createCallbackFcn(app, @edit_RasterScan_ScanPointsValueChanged, true);
            app.edit_RasterScan_ScanPoints.Value = 441;
            app.PixelStepsizeVLabel_3 = uilabel(app.RasterScanTab);
            app.PixelStepsizeVLabel_3.Text = 'Offset X';
            app.edit_RasterScan_OffsetX = uieditfield(app.RasterScanTab, 'numeric');
            app.edit_RasterScan_OffsetX.Limits = [-10 10];
            app.PixelStepsizeVLabel_4 = uilabel(app.RasterScanTab);
            app.PixelStepsizeVLabel_4.Text = 'Offset Y';
            app.edit_RasterScan_OffsetY = uieditfield(app.RasterScanTab, 'numeric');
            app.edit_RasterScan_OffsetY.Limits = [-10 10];
            app.WaveformDropDownLabel_2 = uilabel(app.RasterScanTab);
            app.WaveformDropDownLabel_2.Text = 'Waveform';
            app.dropdown_RasterScan_Waveform = uidropdown(app.RasterScanTab);
            app.dropdown_RasterScan_Waveform.Items = {'Sine', 'Triangle'};
            app.dropdown_RasterScan_Waveform.Value = 'Sine';
            app.FastaxisLabel = uilabel(app.RasterScanTab);
            app.FastaxisLabel.Text = 'Fast axis';
            app.dropdown_RasterScan_FastAxis = uidropdown(app.RasterScanTab);
            app.dropdown_RasterScan_FastAxis.Items = {'X', 'Y'};
            app.dropdown_RasterScan_FastAxis.Value = 'X';
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.DeviceNameEditFieldLabel = uilabel(app.SetupTab);
            app.DeviceNameEditFieldLabel.Text = 'Device Name';
            app.edit_deviceName = uieditfield(app.SetupTab, 'text');
            app.edit_deviceName.Value = 'Dev2';
            app.InitGalvosButton = uibutton(app.SetupTab, 'push');
            app.InitGalvosButton.ButtonPushedFcn = createCallbackFcn(app, @InitGalvosButtonPushed, true);
            app.InitGalvosButton.Text = 'Init Galvos';
            app.GalvoinitializedLampLabel = uilabel(app.SetupTab);
            app.GalvoinitializedLampLabel.Text = 'Galvo initialized';
            app.GalvoinitializedLamp = uilamp(app.SetupTab);
            % Show the figure after all components are created
            app.GalvoControlPanelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GalvoControlPanel

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.GalvoControlPanelUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.GalvoControlPanelUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GalvoControlPanelUIFigure)
        end
    end
end