classdef PowerModulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PowerControl                    matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        menu_debug                      matlab.ui.container.Menu
        menu_about                      matlab.ui.container.Menu
        DisconnectMenu                  matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        PowerTab                        matlab.ui.container.Tab
        button_laserpower_setAbsolute   matlab.ui.control.Button
        label_laserpower_absolute       matlab.ui.control.Label
        edit_laserpower_absolute        matlab.ui.control.NumericEditField
        absoloutePowerLabel             matlab.ui.control.Label
        dropdown_lasepowe_wavelength    matlab.ui.control.DropDown
        currentwavelengthDropDownLabel  matlab.ui.control.Label
        edit_laserpower_percentage      matlab.ui.control.NumericEditField
        LaserpowerEditFieldLabel        matlab.ui.control.Label
        edit_laserpower_postion         matlab.ui.control.NumericEditField
        MotorpositionEditFieldLabel     matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        button_laserpower_move          matlab.ui.control.Button
        button_laserpower_set           matlab.ui.control.Button
        MovingLamp                      matlab.ui.control.Lamp
        MovingLampLabel                 matlab.ui.control.Label
        LaserPowermWLabel               matlab.ui.control.Label
        LaserPowerWatts                 matlab.ui.control.LinearGauge
        LaserPowerGauge                 matlab.ui.control.LinearGauge
        LaserPowerLabel                 matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        TextAreaLabel                   matlab.ui.control.Label
        button_GetPosition              matlab.ui.control.Button
        button_settings_init            matlab.ui.control.Button
        Laserpower3EditField            matlab.ui.control.NumericEditField
        Laserpower3EditFieldLabel       matlab.ui.control.Label
        Laserpower2EditField            matlab.ui.control.NumericEditField
        Laserpower2EditFieldLabel       matlab.ui.control.Label
        Laserpower1EditField            matlab.ui.control.NumericEditField
        Laserpower1EditFieldLabel       matlab.ui.control.Label
        Power3Button                    matlab.ui.control.Button
        Power2Button                    matlab.ui.control.Button
        Power1Button                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        MotorHandle; % Description
        MotorInit;
        currentPosition = 0;
        currentPower = 100;
        calibrationData;
        wavelength = 725;
    end
    
    methods (Access = public)
        
        function position = Power2Position(app,power)
            % Max Power at -1 
            % Min Power at 44
            position = 44-45*power/100;
            if position < 0
                position = position + 360;
            end
        end
        
        function MoveMotorTo(app,pos)
            if isnumeric(pos)
                text_status = ['Motor Position: ',num2str(app.MotorHandle.position)];

              app.TextArea.Value = text_status;
                    app.MotorHandle.moveto(pos);

%                     uiwait(msgbox('Position is not valid!'))
%                 end
            end
%             powerPercentage = Position2Percentage(app,pos);
%             updatePowerIndicator(app,powerPercentage);
            

        end
        
        function updateMotorPosition(app,Position)
            app.edit_laserpower_postion.Value = Position;
        end
        
        function updatePowerIndicator(app,power)
            app.LaserPowerGauge.Value = power;
            app.edit_laserpower_percentage.Value = power;
        end
        
        function power = Position2Percentage(app,position)
                power = calculatePosition2PercentagePower(position);
        end


        function power = Position2Power(app)
            % Max Power at -1 
            % Min Power at 44
            % Calculate Power based on measured calibration Data
            power = 1;
        end
        
        function updateAbsolutePowerIndicator(app,power)
            app.LaserPowerWatts.Value = power;
            app.edit_laserpower_absolute.Value = power;
        end
        
        function updatePowerValues(app)
            power = calculatePosition2PercentagePower(app.calibrationData,app.wavelength,app.currentPosition);
            app.currentPower = power;
            app.LaserPowerGauge.Value = power;
            app.edit_laserpower_percentage.Value = power;
            try 
                absolutePower = calculatePosition2absolutePower(app.calibrationData,app.wavelength,app.currentPosition);
                updateAbsolutePowerIndicator(app,absolutePower);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                % Import MM from handles
                try
                    app.MotorHandle = evalin('base','handles.settings.motor.handle');
                    app.MotorInit = 1;
                    app.button_settings_init.Enable = 'off';
                catch
                    disp('Motor was not initialized in BiofilmToolbox')
                    app.MotorInit = 0;
                end
                assignin('base','app_power',app)
                % import calibration data
                temp = load('Calibration.mat');
                app.calibrationData = temp.data_fit;
                try
                    power = calculatePosition2PercentagePower(app.calibrationData,app.wavelength,app.MotorHandle.position);
                    try 
                        absolutePower = calculatePosition2absolutePower(app.calibrationData,app.wavelength,app.MotorHandle.position);
                        updateAbsolutePowerIndicator(app,absolutePower);
                    catch 
                        absolutePower = 0;
                    end
                    disp(['Motor Position: ',num2str(app.MotorHandle.position),'° == ',num2str(power),' % Power == ', num2str(absolutePower),' mW']);
                end
                % app.PowerControl.WindowStyle = 'alwaysontop';
        end

        % Button pushed function: button_laserpower_set
        function button_laserpower_setPushed(app, event)
            power_input = app.edit_laserpower_percentage.Value;
            app.MovingLamp.Color = [1,0,0];
            if power_input > 65
                app.PowerControl.WindowStyle = 'normal';
    
                answ = questdlg('Confirm that you need more than 60% Laserpower!','Attention','Yes','No','No');
                if strcmp(answ,'Yes')
                    position = calculatePercentagePower2Position(app.calibrationData,app.wavelength,power_input);
                    try
                        absolutePower = calculatePosition2absolutePower(app.calibrationData,app.wavelength,position);
                        updateAbsolutePowerIndicator(app,absolutePower);
                    catch 
                        absolutePower = 0;
                    end
                    MoveMotorTo(app,position);
                    updatePowerIndicator(app,power_input);
                    app.currentPosition = position;
                    app.currentPower = power_input;
                    app.edit_laserpower_postion.Value = position;

                else
                    disp('Laserpower was not changed!')
                    app.edit_laserpower_postion.Value = app.MotorHandle.position;
                    app.edit_laserpower_percentage.Value =  app.currentPower;
                    absolutePower = 0;

                end
            else
                position = calculatePercentagePower2Position(app.calibrationData,app.wavelength,power_input);
                MoveMotorTo(app,position);
                updatePowerIndicator(app,power_input)
                updateMotorPosition(app,position)
                app.currentPosition = position;
                app.currentPower = power_input;
                try
                    absolutePower = calculatePosition2absolutePower(app.calibrationData,app.wavelength,position);
                    updateAbsolutePowerIndicator(app,absolutePower);
                catch 
                    absolutePower = 0;
                end
            end
            % disp(['Motor Position: ',num2str(app.MotorHandle.position),'° == ',num2str(power_input),' % Power.']);
            disp(['Motor Position: ',num2str(app.MotorHandle.position),'° == ',num2str(power_input),' % Power == ', num2str(absolutePower),' mW']);
            app.MovingLamp.Color = [0,1,0];

        end

        % Button pushed function: button_laserpower_move
        function button_laserpower_movePushed(app, event)
            position = app.edit_laserpower_postion.Value;
            power = calculatePosition2PercentagePower(app.calibrationData,app.wavelength,position);
            app.MovingLamp.Color = [1,0,0];
            if power > 65
                answ = questdlg('Confirm that you need more than 60% Laserpower!','Attention','Yes','No','No');
                if strcmp(answ,'Yes')
                    MoveMotorTo(app,position);
                    updatePowerIndicator(app,power)
                    updateMotorPosition(app,position)
                    app.currentPosition = position;
                    app.currentPower = power;
                    app.edit_laserpower_percentage.Value =  power;
                    try 
                        absolutePower = calculatePosition2absolutePower(app.calibrationData,app.wavelength,position);
                        updateAbsolutePowerIndicator(app,absolutePower);
                    catch
                        absolutePower = 0;
                    end

                else
                    disp('Laserpower was not changed!')
                    app.edit_laserpower_postion.Value = app.currentPosition;
                    app.edit_laserpower_percentage.Value =  app.currentPower;
                    absolutePower = 0;
                end
            else
                MoveMotorTo(app,position);
                updatePowerIndicator(app,power)
                updateMotorPosition(app,position);
                app.currentPosition = position;
                app.currentPower = power;
                app.edit_laserpower_percentage.Value = power;
                try 
                    absolutePower = calculatePosition2absolutePower(app.calibrationData,app.wavelength,position);
                    updateAbsolutePowerIndicator(app,absolutePower);
                catch 
                    absolutePower = 0;
                end
            end            
            app.MovingLamp.Color = [0,1,0];
            % Get absolute power
            disp(['Motor Position: ',num2str(app.MotorHandle.position),'° == ',num2str(power),' % Power == ', num2str(absolutePower),' mW']);

        end

        % Button pushed function: button_settings_init
        function button_settings_initPushed(app, event)
            app.MotorHandle = motor;
            app.MotorHandle.connect('55249114');
            app.MotorInit = 1;
            app.MotorHandle.home();
            app.button_settings_init.Enable = 'off';
            assignin('base','MotorHandle',app.MotorHandle)
        end

        % Button pushed function: button_GetPosition
        function GetPositionButtonPushed(app, event)
            power = calculatePosition2PercentagePower(app.calibrationData,app.wavelength,app.MotorHandle.position);
            try 
                absolutePower = calculatePosition2absolutePower(app.calibrationData,app.wavelength,app.MotorHandle.position);
                updateAbsolutePowerIndicator(app,absolutePower);
            catch 
                absolutePower = 0;
            end
            text_status = ['Motor Position: ',num2str(app.MotorHandle.position),'° == ',num2str(power),' % Power == ', num2str(absolutePower),' mW'];
            app.TextArea.Value = text_status;
            disp(['Motor Position: ',num2str(app.MotorHandle.position),'° == ',num2str(power),' % Power == ', num2str(absolutePower),' mW']);

        end

        % Menu selected function: menu_debug
        function menu_debugSelected(app, event)
            assignin('base','app_power',app)

        end

        % Button pushed function: Power1Button
        function Power1ButtonPushed(app, event)
            power_input = app.Laserpower1EditField.Value;
            position = calculatePercentagePower2Position(app.calibrationData,app.wavelength,power_input);
            try
                absolutePower = calculatePosition2absolutePower(app.calibrationData,app.wavelength,position);
                updateAbsolutePowerIndicator(app,absolutePower);
            catch 
                absolutePower = 0;
            end
            MoveMotorTo(app,position);
            updatePowerIndicator(app,power_input)
            updateMotorPosition(app,position)
            app.currentPosition = position;
            app.currentPower = power_input;
            updatePowerValues(app);
            disp(['Motor Position: ',num2str(app.MotorHandle.position),'° == ',num2str(power_input),' % Power == ', num2str(absolutePower),' mW']);

        end

        % Value changed function: Laserpower2EditField
        function Laserpower2EditFieldValueChanged(app, event)

        end

        % Value changed function: Laserpower3EditField
        function Laserpower3EditFieldValueChanged(app, event)
           
            
        end

        % Button pushed function: Power3Button
        function Power3ButtonPushed(app, event)
            power_input = app.Laserpower3EditField.Value;
            position = calculatePercentagePower2Position(app.calibrationData,app.wavelength,power_input);
            try
                absolutePower = calculatePosition2absolutePower(app.calibrationData,app.wavelength,position);
                updateAbsolutePowerIndicator(app,absolutePower);
            catch 
                absolutePower = 0;
            end
            MoveMotorTo(app,position);
            updatePowerIndicator(app,power_input)
            updateMotorPosition(app,position)
            app.currentPosition = position;
            app.currentPower = power_input;
            updatePowerValues(app);
            disp(['Motor Position: ',num2str(app.MotorHandle.position),'° == ',num2str(power_input),' % Power == ', num2str(absolutePower),' mW']);
        end

        % Button pushed function: Power2Button
        function Power2ButtonPushed(app, event)
            power_input = app.Laserpower2EditField.Value;
            position = calculatePercentagePower2Position(app.calibrationData,app.wavelength,power_input);
            try
                absolutePower = calculatePosition2absolutePower(app.calibrationData,app.wavelength,position);
                updateAbsolutePowerIndicator(app,absolutePower);
            catch 
                absolutePower = 0;
            end
            MoveMotorTo(app,position);
            updatePowerIndicator(app,power_input)
            updateMotorPosition(app,position)
            app.currentPosition = position;
            app.currentPower = power_input;
            updatePowerValues(app);
            disp(['Motor Position: ',num2str(app.MotorHandle.position),'° == ',num2str(power_input),' % Power == ', num2str(absolutePower),' mW']);
        end

        % Menu selected function: menu_about
        function menu_aboutMenuSelected(app, event)
            uiconfirm(app.PowerControl,'Power modulation developed by Konstantin Neuhaus (konneuhaus@gmail.com)','About','Icon','info','Options',{'OK'})

        end

        % Value changed function: dropdown_lasepowe_wavelength
        function dropdown_lasepowe_wavelengthValueChanged(app, event)
            value = app.dropdown_lasepowe_wavelength.Value;
            app.wavelength = str2double(value(1:3));
            updatePowerValues(app)
        end

        % Menu selected function: DisconnectMenu
        function DisconnectMenuSelected(app, event)
            app.MotorHandle.disconnect;
            app.button_settings_init.Enable = 1;
        end

        % Button pushed function: button_laserpower_setAbsolute
        function button_laserpower_setAbsoluteButtonPushed(app, event)
            power_inputAbsolute = app.edit_laserpower_absolute.Value;
            % Check if in Range
            % find index
            index = find([app.calibrationData.wavelengths] == app.wavelength);
            app.MovingLamp.Color = [1,0,0];

            if power_inputAbsolute <= app.calibrationData(index).MaxPower
                relativePower = calculateAbsolutePower2Percentage(app.calibrationData,app.wavelength,power_inputAbsolute);
                    if relativePower > 65
                        app.PowerControl.WindowStyle = 'normal';
                        answ = questdlg('Confirm that you need more than 60% Laserpower!','Attention','Yes','No','No');
                        if strcmp(answ,'Yes')
                            position = calculatePercentagePower2Position(app.calibrationData,app.wavelength,relativePower);
                            MoveMotorTo(app,position);
                            updatePowerIndicator(app,relativePower);
                            app.currentPosition = position;
                            app.currentPower = relativePower;
                            app.edit_laserpower_postion.Value = position;
                            updateAbsolutePowerIndicator(app,power_inputAbsolute);
            
                        else
                            disp('Laserpower was not changed!')
                            app.edit_laserpower_postion.Value = app.MotorHandle.position;
                            app.edit_laserpower_percentage.Value =  app.currentPower;
                        end
                    else
                        position = calculatePercentagePower2Position(app.calibrationData,app.wavelength,relativePower);
                        MoveMotorTo(app,position);
                        updatePowerIndicator(app,relativePower)
                        updateMotorPosition(app,position)
                        app.currentPosition = position;
                        app.currentPower = power_inputAbsolute;
                        updateAbsolutePowerIndicator(app,power_inputAbsolute);
                    end
                        disp(['Motor Position: ',num2str(app.MotorHandle.position),'° == ',num2str(relativePower),' % Power == ', num2str(power_inputAbsolute),' mW']);
            else
                warning('Requested Power is above available Power!')
            end
            app.MovingLamp.Color = [0,1,0];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PowerControl = uifigure('Visible', 'off');
            app.PowerControl.Name = 'Power Modulation App';
            app.PowerControl.Icon = fullfile(pathToMLAPP, 'icon_cropped.png');
            app.Menu = uimenu(app.PowerControl);
            app.Menu.Text = 'Menu';
            app.menu_debug = uimenu(app.Menu);
            app.menu_debug.MenuSelectedFcn = createCallbackFcn(app, @menu_debugSelected, true);
            app.menu_debug.Text = 'Debug';
            app.menu_about = uimenu(app.Menu);
            app.menu_about.MenuSelectedFcn = createCallbackFcn(app, @menu_aboutMenuSelected, true);
            app.menu_about.Text = 'About';
            app.DisconnectMenu = uimenu(app.Menu);
            app.DisconnectMenu.MenuSelectedFcn = createCallbackFcn(app, @DisconnectMenuSelected, true);
            app.DisconnectMenu.Text = 'Disconnect';
            app.TabGroup = uitabgroup(app.PowerControl);
            app.PowerTab = uitab(app.TabGroup);
            app.PowerTab.Title = 'Power';
            app.LaserPowerLabel = uilabel(app.PowerTab);
            app.LaserPowerLabel.Text = 'Laser Power [%]';
            app.LaserPowerGauge = uigauge(app.PowerTab, 'linear');
            app.LaserPowerGauge.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.LaserPowerWatts = uigauge(app.PowerTab, 'linear');
            app.LaserPowerWatts.Limits = [0 1800];
            app.LaserPowermWLabel = uilabel(app.PowerTab);
            app.LaserPowermWLabel.Text = 'Laser Power [mW]';
            app.MovingLampLabel = uilabel(app.PowerTab);
            app.MovingLampLabel.Text = 'Moving';
            app.MovingLamp = uilamp(app.PowerTab);
            app.button_laserpower_set = uibutton(app.PowerTab, 'push');
            app.button_laserpower_set.ButtonPushedFcn = createCallbackFcn(app, @button_laserpower_setPushed, true);
            app.button_laserpower_set.Text = 'Set';
            app.button_laserpower_move = uibutton(app.PowerTab, 'push');
            app.button_laserpower_move.ButtonPushedFcn = createCallbackFcn(app, @button_laserpower_movePushed, true);
            app.button_laserpower_move.Text = 'Move';
            app.Label = uilabel(app.PowerTab);
            app.Label.Text = '%';
            app.Label_2 = uilabel(app.PowerTab);
            app.Label_2.Text = '°';
            app.MotorpositionEditFieldLabel = uilabel(app.PowerTab);
            app.MotorpositionEditFieldLabel.Text = 'Motor position';
            app.edit_laserpower_postion = uieditfield(app.PowerTab, 'numeric');
            app.LaserpowerEditFieldLabel = uilabel(app.PowerTab);
            app.LaserpowerEditFieldLabel.Text = 'Laserpower';
            app.edit_laserpower_percentage = uieditfield(app.PowerTab, 'numeric');
            app.edit_laserpower_percentage.Limits = [0 100];
            app.currentwavelengthDropDownLabel = uilabel(app.PowerTab);
            app.currentwavelengthDropDownLabel.Text = 'current wavelength';
            app.dropdown_lasepowe_wavelength = uidropdown(app.PowerTab);
            app.dropdown_lasepowe_wavelength.Items = {'710nm', '715nm', '720nm', '725nm', '730nm', '735nm', '740nm', '745nm', '750nm', '755nm', '760nm', '765nm', '770nm', '775nm', '780nm', '785nm', '790nm', '795nm', '800nm', '805nm', '810nm', '815nm', '820nm', '825nm', '830nm', '835nm', '840nm', '845nm', '850nm', '855nm', '860nm', '865nm', '870nm', '875nm', '880nm', '885nm', '890nm', '895nm', '900nm', '905nm', '910nm', '915nm', '920nm', '925nm', '930nm', '935nm', '940nm', '945nm', '950nm', '955nm', '960nm', '965nm', '970nm', '975nm', '980nm', '985nm', '990nm'};
            app.dropdown_lasepowe_wavelength.ValueChangedFcn = createCallbackFcn(app, @dropdown_lasepowe_wavelengthValueChanged, true);
            app.dropdown_lasepowe_wavelength.Value = '725nm';
            app.absoloutePowerLabel = uilabel(app.PowerTab);
            app.absoloutePowerLabel.Text = 'absoloute Power';
            app.edit_laserpower_absolute = uieditfield(app.PowerTab, 'numeric');
            app.edit_laserpower_absolute.Limits = [0 2000];
            app.edit_laserpower_absolute.ValueDisplayFormat = '%.0f';
            app.label_laserpower_absolute = uilabel(app.PowerTab);
            app.label_laserpower_absolute.Text = 'mW';
            app.button_laserpower_setAbsolute = uibutton(app.PowerTab, 'push');
            app.button_laserpower_setAbsolute.ButtonPushedFcn = createCallbackFcn(app, @button_laserpower_setAbsoluteButtonPushed, true);
            app.button_laserpower_setAbsolute.Text = 'Set';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.Power1Button = uibutton(app.SettingsTab, 'push');
            app.Power1Button.ButtonPushedFcn = createCallbackFcn(app, @Power1ButtonPushed, true);
            app.Power1Button.Text = 'Power 1';
            app.Power2Button = uibutton(app.SettingsTab, 'push');
            app.Power2Button.ButtonPushedFcn = createCallbackFcn(app, @Power2ButtonPushed, true);
            app.Power2Button.Text = 'Power 2';
            app.Power3Button = uibutton(app.SettingsTab, 'push');
            app.Power3Button.ButtonPushedFcn = createCallbackFcn(app, @Power3ButtonPushed, true);
            app.Power3Button.Text = 'Power 3';
            app.Laserpower1EditFieldLabel = uilabel(app.SettingsTab);
            app.Laserpower1EditFieldLabel.Text = 'Laserpower  1';
            app.Laserpower1EditField = uieditfield(app.SettingsTab, 'numeric');
            app.Laserpower1EditField.Limits = [0 100];
            app.Laserpower1EditField.Value = 0.1;
            app.Laserpower2EditFieldLabel = uilabel(app.SettingsTab);
            app.Laserpower2EditFieldLabel.Text = 'Laserpower  2';
            app.Laserpower2EditField = uieditfield(app.SettingsTab, 'numeric');
            app.Laserpower2EditField.Limits = [0 100];
            app.Laserpower2EditField.ValueChangedFcn = createCallbackFcn(app, @Laserpower2EditFieldValueChanged, true);
            app.Laserpower2EditField.Value = 1;
            app.Laserpower3EditFieldLabel = uilabel(app.SettingsTab);
            app.Laserpower3EditFieldLabel.Text = 'Laserpower  3';
            app.Laserpower3EditField = uieditfield(app.SettingsTab, 'numeric');
            app.Laserpower3EditField.Limits = [0 100];
            app.Laserpower3EditField.ValueChangedFcn = createCallbackFcn(app, @Laserpower3EditFieldValueChanged, true);
            app.Laserpower3EditField.Value = 10;
            app.button_settings_init = uibutton(app.SettingsTab, 'push');
            app.button_settings_init.ButtonPushedFcn = createCallbackFcn(app, @button_settings_initPushed, true);
            app.button_settings_init.Text = 'Init';
            app.button_GetPosition = uibutton(app.SettingsTab, 'push');
            app.button_GetPosition.ButtonPushedFcn = createCallbackFcn(app, @GetPositionButtonPushed, true);
            app.button_GetPosition.Text = 'Get Position';
            app.TextAreaLabel = uilabel(app.SettingsTab);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.SettingsTab);
            % Show the figure after all components are created
            app.PowerControl.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PowerModulation

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PowerControl)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.PowerControl)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PowerControl)
        end
    end
end