classdef PowerMeasurement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LaserPower                     matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        DebugMenu                      matlab.ui.container.Menu
        ImportMenu                     matlab.ui.container.Menu
        ExportMenu                     matlab.ui.container.Menu
        PlotDataMenu                   matlab.ui.container.Menu
        ClosePowermeterButton          matlab.ui.control.Button
        InitMicromanagerButton         matlab.ui.control.Button
        LegendTextArea                 matlab.ui.control.TextArea
        LegendTextAreaLabel            matlab.ui.control.Label
        AveragesEditField              matlab.ui.control.NumericEditField
        AveragesEditFieldLabel         matlab.ui.control.Label
        StopMeasurementButton          matlab.ui.control.Button
        StartMeasurementButton         matlab.ui.control.Button
        SelectOutputDirectoryButton    matlab.ui.control.Button
        InitPowermeterButton           matlab.ui.control.Button
        COMPortPowermeterEditField     matlab.ui.control.NumericEditField
        COMPortPowermeterEditFieldLabel  matlab.ui.control.Label
        StepsizeEditField              matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel         matlab.ui.control.Label
        MicroscopeDropDown             matlab.ui.control.DropDown
        MicroscopeDropDownLabel        matlab.ui.control.Label
        OutputDirectoryEditField       matlab.ui.control.EditField
        OutputDirectoryEditFieldLabel  matlab.ui.control.Label
        UIAxes6                        matlab.ui.control.UIAxes
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
      
    end

    properties (Access = public)
      oldpath = addpath('.\Micromanager-Configs');
        oldpath2 = addpath('.\Micromanager')
        microscope;
        MM;
        mmc;

        stop % Description
        data; 
        powermeterDevice % Description
    end

    methods (Access = private)
       
        function updatePlot(app,laser,dichroic,laser_list_wavelengths)            
            if laser == 1
                cla(app.UIAxes);
                for dic = 1:dichroic
                    plot(app.UIAxes,app.data(:).Laser_Percentage_Power,1000*app.data(1).(char(laser_list_wavelengths(laser)))(dic,:));
                end
            elseif laser == 2
                cla(app.UIAxes2);
                for dic = 1:dichroic
                    plot(app.UIAxes2,app.data(:).Laser_Percentage_Power,1000*app.data(1).(char(laser_list_wavelengths(laser)))(dic,:));
                end 
            elseif laser == 3
                cla(app.UIAxes3);                
                for dic = 1:dichroic
                    plot(app.UIAxes3,app.data(:).Laser_Percentage_Power,1000*app.data(1).(char(laser_list_wavelengths(laser)))(dic,:));
                end 
            elseif laser == 4
                cla(app.UIAxes4);                
                for dic = 1:dichroic
                    plot(app.UIAxes4,app.data(:).Laser_Percentage_Power,1000*app.data(1).(char(laser_list_wavelengths(laser)))(dic,:));
                end 
            elseif laser == 5
                cla(app.UIAxes5);                
                for dic = 1:dichroic
                    plot(app.UIAxes5,app.data(:).Laser_Percentage_Power,1000*app.data(1).(char(laser_list_wavelengths(laser)))(dic,:));
                end 
            elseif laser == 6
                cla(app.UIAxes6);                
                for dic = 1:dichroic
                    plot(app.UIAxes6,app.data(:).Laser_Percentage_Power,1000*app.data(1).(char(laser_list_wavelengths(laser)))(dic,:));
                end 
            end
        end
        
        function updateMicroscope(app)
            clearAxis(app);
            % Changes title of UIAxes and number of visible ones
            if strcmp(app.MicroscopeDropDown.Value,'Confocal 2') %New Confocal
                app.UIAxes.Title.String = '405nm';  
                app.UIAxes2.Title.String = '488nm';
                app.UIAxes3.Title.String = '505nm';
                app.UIAxes4.Title.String = '552nm';
                app.UIAxes5.Title.String = '594nm';
                app.UIAxes6.Title.String = '640nm';
                app.UIAxes2.Visible = 'On';
                app.UIAxes3.Visible = 'On';
                app.UIAxes4.Visible = 'On';
                app.UIAxes5.Visible = 'On';
                app.UIAxes6.Visible = 'On';
            elseif strcmp(app.MicroscopeDropDown.Value,'Confocal 1') % Old Confocal
                app.UIAxes.Title.String = '405nm';
                app.UIAxes2.Title.String = '488nm';
                app.UIAxes3.Title.String = '514nm';
                app.UIAxes4.Title.String = '552nm';
                app.UIAxes5.Title.String = '594nm';                
                app.UIAxes6.Title.String = '637nm';
                app.UIAxes2.Visible = 'On';
                app.UIAxes3.Visible = 'On';
                app.UIAxes4.Visible = 'On';
                app.UIAxes5.Visible = 'On';
                app.UIAxes6.Visible = 'On';
                app.COMPortPowermeterEditField.Value = 8;
            elseif strcmp(app.MicroscopeDropDown.Value,'Confocal 4') % 3rd/PA Confocal
                app.UIAxes.Title.String = '405nm';
                app.UIAxes2.Title.String = '488nm';
                app.UIAxes3.Title.String = '514nm';
                app.UIAxes4.Title.String = '552nm';
                app.UIAxes5.Title.String = '594nm';                
                app.UIAxes6.Title.String = '637nm';
                app.UIAxes2.Visible = 'On';
                app.UIAxes3.Visible = 'On';
                app.UIAxes4.Visible = 'On';
                app.UIAxes5.Visible = 'On';
                app.UIAxes6.Visible = 'On';
            elseif strcmp(app.MicroscopeDropDown.Value,'Confocal 3') % old EPI
                app.UIAxes.Title.String = '445nm';
                app.UIAxes2.Title.String = '488nm';
                app.UIAxes3.Title.String = '514nm';
                app.UIAxes4.Title.String = '552nm';
                app.UIAxes5.Title.String = '637nm';
                app.UIAxes2.Visible = 'On';
                app.UIAxes3.Visible = 'On';
                app.UIAxes4.Visible = 'On';
                app.UIAxes5.Visible = 'On';
                app.UIAxes6.Visible = 'Off';

            elseif strcmp(app.MicroscopeDropDown.Value,'APOM') % APOM
                app.UIAxes2.Visible = 'Off';
                app.UIAxes3.Visible = 'Off';
                app.UIAxes4.Visible = 'Off';
                app.UIAxes5.Visible = 'Off';
                app.UIAxes.Title.String = '488nm';
            end
        end
        
        function clearAxis(app)
            cla(app.UIAxes);                
            cla(app.UIAxes2);                
            cla(app.UIAxes3);                
            cla(app.UIAxes4);                
            cla(app.UIAxes5); 
            try
                cla(app.UIAxes6);                
            end

        end
        
        function Export_PlotData(app)
            outputDir = app.OutputDirectoryEditField.Value;
            % Get selected microscope
            if strcmp(app.MicroscopeDropDown.Value,'Confocal 2') %New Confocal
                for i = 1:3
                    L405(i,:) = app.data(1).L405(i,:);
                    L488(i,:) = app.data(1).L488(i,:);
                    L505(i,:) = app.data(1).L505(i,:);
                    L552(i,:) = app.data(1).L552(i,:);
                    L594(i,:) = app.data(1).L594(i,:);
                    L640(i,:) = app.data(1).L640(i,:);
                end
                % Substract Offset == Background Measurement at 0% Laserpower
                for i = 1:3
                    for k = 1:length(L488)
                        L405(i,k) = (L405(i,k)-L405(i,1))*1000;
                        if L405(i,k) < 0
                            L405(i,k) = 0;
                        end                         
                        L488(i,k) = (L488(i,k)-L488(i,1))*1000;
                        if L488(i,k) < 0
                            L488(i,k) = 0;
                        end 
                        L505(i,k) = (L505(i,k)-L505(i,1))*1000;
                        if L505(i,k) < 0
                            L505(i,k) = 0;
                        end 
                        L552(i,k) =  (L552(i,k)- L552(i,1))*1000;
                        if L552(i,k) < 0
                            L552(i,k) = 0;
                        end 
                        L594(i,k) = (L594(i,k)-L594(i,1))*1000;
                        if L594(i,k) < 0
                            L594(i,k) = 0;
                        end     
                        L640(i,k) = (L640(i,k)-L640(i,1))*1000;
                        if L640(i,k) < 0
                            L640(i,k) = 0;
                        end 
                    end
                end
                dx = 100/(length(L488)-1);
                x = 0:dx:100;
                % 488nm
                y1 = L488(1,:);
                y2 = L488(2,:);
                y3 = L488(3,:);
                f1 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('488nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f1,fullfile(outputDir,'new_confocal_Laser_488nm'),'-dpng')               
                % 505nm
                y1 = L505(1,:);
                y2 = L505(2,:);
                y3 = L505(3,:);
                f2 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('505nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f2,fullfile(outputDir,'new_confocal_Laser_505nm'),'-dpng')
                % 552nm
                y1 = L552(1,:);
                y2 = L552(2,:);
                y3 = L552(3,:);
                f3 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('552nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f3,fullfile(outputDir,'new_confocal_Laser_552nm'),'-dpng')
                % 594nm
                y1 = L594(1,:);
                y2 = L594(2,:);
                y3 = L594(3,:);
                f4 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title(gca,'594nm Laser','FontSize',18,'FontWeight','bold');
                ylim(gca,[0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f4,fullfile(outputDir,'new_confocal_Laser_594nm'),'-dpng')
                % 640nm
                y1 = L640(1,:);
                y2 = L640(2,:);
                y3 = L640(3,:);
                f5 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('640nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f5,fullfile(outputDir,'new_confocal_Laser_640nm'),'-dpng')
                close all;
                % 405nm
                y1 = L405(1,:);
                y2 = L405(2,:);
                y3 = L405(3,:);
                f1 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('405nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f1,fullfile(outputDir,'new_confocal_Laser_405nm'),'-dpng')               
            elseif strcmp(app.MicroscopeDropDown.Value,'Confocal 1') % Old Confocal
               for i = 1:3
					L405(i,:) = app.data(1).L405(i,:);
                    L488(i,:) = app.data(1).L488(i,:);
                    L514(i,:) = app.data(1).L514(i,:);
                    L552(i,:) = app.data(1).L552(i,:);
                    L594(i,:) = app.data(1).L594(i,:);
                    L637(i,:) = app.data(1).L637(i,:);
                end
                % Substract Offset == Background Measurement at 0% Laserpower
                for i = 1:3
                    for k = 1:length(L488)
						L405(i,k) = (L405(i,k)-L405(i,1))*1000;
                        if L405(i,k) < 0
                            L405(i,k) = 0;
                        end     
                        L488(i,k) = (L488(i,k)-L488(i,1))*1000;
                        if L488(i,k) < 0
                            L488(i,k) = 0;
                        end 
                        L514(i,k) = (L514(i,k)-L514(i,1))*1000;
                        if L514(i,k) < 0
                            L514(i,k) = 0;
                        end 
                        L552(i,k) =  (L552(i,k)- L552(i,1))*1000;
                        if L552(i,k) < 0
                            L552(i,k) = 0;
                        end 
                        L594(i,k) =  (L594(i,k)- L594(i,1))*1000;
                        if L594(i,k) < 0
                            L594(i,k) = 0;
                        end 
                        L637(i,k) = (L637(i,k)-L637(i,1))*1000;
                        if L637(i,k) < 0
                            L637(i,k) = 0;
                        end 
                    end
                end
                dx = 100/(length(L488)-1);
                x = 0:dx:100;
				  % 445nm
                y1 = L405(1,:);
                y2 = L405(2,:);
                y3 = L405(3,:);
                f4 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('405nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f4,fullfile(outputDir,'Confocal1_Laser_405nm'),'-dpng')
                % 488nm
                y1 = L488(1,:);
                y2 = L488(2,:);
                y3 = L488(3,:);
                f1 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('488nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f1,fullfile(outputDir,'Confocal1_Laser_488nm'),'-dpng')               
                % 514nm
                y1 = L514(1,:);
                y2 = L514(2,:);
                y3 = L514(3,:);
                f2 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('514nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f2,fullfile(outputDir,'Confocal1_Laser_514nm'),'-dpng')
                % 552nm
                y1 = L552(1,:);
                y2 = L552(2,:);
                y3 = L552(3,:);
                f3 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('552nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f3,fullfile(outputDir,'Confocal1_Laser_552nm'),'-dpng')
                % 594nm
                y1 = L594(1,:);
                y2 = L594(2,:);
                y3 = L594(3,:);
                f3 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('594nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f3,fullfile(outputDir,'Confocal1_Laser_594nm'),'-dpng')        
                % 637nm
                y1 = L637(1,:);
                y2 = L637(2,:);
                y3 = L637(3,:);
                f5 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('637nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f5,fullfile(outputDir,'Confocal1_Laser_637nm'),'-dpng')
                close all;
            elseif strcmp(app.MicroscopeDropDown.Value,'Confocal 3') % old EPI
                for i = 1:3
					L445(i,:) = app.data(1).L445(i,:);
                    L488(i,:) = app.data(1).L488(i,:);
                    L514(i,:) = app.data(1).L514(i,:);
                    L552(i,:) = app.data(1).L552(i,:);
                    L637(i,:) = app.data(1).L637(i,:);
                end
                % Substract Offset == Background Measurement at 0% Laserpower
                for i = 1:3
                    for k = 1:length(L488)
						L445(i,k) = (L445(i,k)-L445(i,1))*1000;
                        if L445(i,k) < 0
                            L445(i,k) = 0;
                        end     
                        L488(i,k) = (L488(i,k)-L488(i,1))*1000;
                        if L488(i,k) < 0
                            L488(i,k) = 0;
                        end 
                        L514(i,k) = (L514(i,k)-L514(i,1))*1000;
                        if L514(i,k) < 0
                            L514(i,k) = 0;
                        end 
                        L552(i,k) =  (L552(i,k)- L552(i,1))*1000;
                        if L552(i,k) < 0
                            L552(i,k) = 0;
                        end 

                        L637(i,k) = (L637(i,k)-L637(i,1))*1000;
                        if L637(i,k) < 0
                            L637(i,k) = 0;
                        end 
                    end
                end
                dx = 100/(length(L488)-1);
                x = 0:dx:100;
				  % 445nm
                y1 = L445(1,:);
                y2 = L445(2,:);
                y3 = L445(3,:);
                f4 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('445nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f4,fullfile(outputDir,'Confocal3_Laser_445nm'),'-dpng')
                % 488nm
                y1 = L488(1,:);
                y2 = L488(2,:);
                y3 = L488(3,:);
                f1 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('488nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f1,fullfile(outputDir,'Confocal3_Laser_488nm'),'-dpng')               
                % 514nm
                y1 = L514(1,:);
                y2 = L514(2,:);
                y3 = L514(3,:);
                f2 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('514nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f2,fullfile(outputDir,'Confocal3_Laser_514nm'),'-dpng')
                % 552nm
                y1 = L552(1,:);
                y2 = L552(2,:);
                y3 = L552(3,:);
                f3 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('552nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f3,fullfile(outputDir,'Confocal3_Laser_552nm'),'-dpng')
                % 637nm
                y1 = L637(1,:);
                y2 = L637(2,:);
                y3 = L637(3,:);
                f5 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('637nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f5,fullfile(outputDir,'Confocal3_Laser_637nm'),'-dpng')
                close all;    
            elseif strcmp(app.MicroscopeDropDown.Value,'Confocal 4') % 3rd/PA Confocal
                for i = 1:3
					L405(i,:) = app.data(1).L405(i,:);
                    L488(i,:) = app.data(1).L488(i,:);
                    L514(i,:) = app.data(1).L514(i,:);
                    L552(i,:) = app.data(1).L552(i,:);
                    L594(i,:) = app.data(1).L594(i,:);
                    L637(i,:) = app.data(1).L637(i,:);
                end
                % Substract Offset == Background Measurement at 0% Laserpower
                for i = 1:3
                    for k = 1:length(L488)
						L405(i,k) = (L405(i,k)-L405(i,1))*1000;
                        if L405(i,k) < 0
                            L405(i,k) = 0;
                        end     
                        L488(i,k) = (L488(i,k)-L488(i,1))*1000;
                        if L488(i,k) < 0
                            L488(i,k) = 0;
                        end 
                        L514(i,k) = (L514(i,k)-L514(i,1))*1000;
                        if L514(i,k) < 0
                            L514(i,k) = 0;
                        end 
                        L552(i,k) =  (L552(i,k)- L552(i,1))*1000;
                        if L552(i,k) < 0
                            L552(i,k) = 0;
                        end 
                        L594(i,k) =  (L594(i,k)- L594(i,1))*1000;
                        if L594(i,k) < 0
                            L594(i,k) = 0;
                        end 
                        L637(i,k) = (L637(i,k)-L637(i,1))*1000;
                        if L637(i,k) < 0
                            L637(i,k) = 0;
                        end 
                    end
                end
                dx = 100/(length(L488)-1);
                x = 0:dx:100;
				  % 445nm
                y1 = L405(1,:);
                y2 = L405(2,:);
                y3 = L405(3,:);
                f4 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('405nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f4,fullfile(outputDir,'Confocal4_Laser_405nm'),'-dpng')
                % 488nm
                y1 = L488(1,:);
                y2 = L488(2,:);
                y3 = L488(3,:);
                f1 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('488nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f1,fullfile(outputDir,'Confocal4_Laser_488nm'),'-dpng')               
                % 514nm
                y1 = L514(1,:);
                y2 = L514(2,:);
                y3 = L514(3,:);
                f2 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('514nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f2,fullfile(outputDir,'Confocal4_Laser_514nm'),'-dpng')
                % 552nm
                y1 = L552(1,:);
                y2 = L552(2,:);
                y3 = L552(3,:);
                f3 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('552nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f3,fullfile(outputDir,'Confocal4_Laser_552nm'),'-dpng')
                % 594nm
                y1 = L594(1,:);
                y2 = L594(2,:);
                y3 = L594(3,:);
                f3 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('594nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f3,fullfile(outputDir,'Confocal4_Laser_594nm'),'-dpng')        
                % 637nm
                y1 = L637(1,:);
                y2 = L637(2,:);
                y3 = L637(3,:);
                f5 = figure;
                plot(x,y1,'-o',x,y2,'-o',x,y3,'-o','MarkerSize',4,'LineWidth',2)
                set(gca,'Fontsize',14,'LineWidth',1.5,'FontWeight','bold');
                title('637nm Laser','FontSize',18,'FontWeight','bold');
                ylim([0 max([y1,y2,y3])]);
                xlabel('Set Laserpower in %','FontSize',14,'FontWeight','bold');
                ylabel('Measured Laserpower in mW','FontSize',14,'FontWeight','bold');
                print(f5,fullfile(outputDir,'Confocal4_Laser_637nm'),'-dpng')
                close all;
            elseif strcmp(app.MicroscopeDropDown.Value,'APOM') % APOM

            end

        end

        function focusFigure(app)
            app.LaserPower.Visible = 'off';
            app.LaserPower.Visible = 'on';
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Init Micromanager to control Microscopes (Dichroics and Lasers)
            if  strcmp(getenv('COMPUTERNAME'),'PCSY264') % New Confocal
                app.microscope = 'Confocal 2';
                app.MicroscopeDropDown.Value = 'Confocal 2';
                updateMicroscope(app);
                app.COMPortPowermeterEditField.Value = 4;
            elseif strcmp(getenv('COMPUTERNAME'),'PCSY168') % Old Confocal
                app.microscope = 'Confocal 1';
                app.MicroscopeDropDown.Value = 'Confocal 1';
                updateMicroscope(app);
                app.COMPortPowermeterEditField.Value ='COM4';  
            elseif strcmp(getenv('COMPUTERNAME'),'PCSY259') % Confocal 4
                app.microscope = 'Confocal 4';
                app.MicroscopeDropDown.Value = 'Confocal 4';
                updateMicroscope(app);
                app.COMPortPowermeterEditField.Value = 7;
            elseif strcmp(getenv('COMPUTERNAME'),'PCSY136') %old EPI
                app.microscope = 'Confocal 3';
                app.MicroscopeDropDown.Value = 'Confocal 3';
                updateMicroscope(app);
                app.COMPortPowermeterEditField.Value = 7;
            end          
%             powermeter_port = inputdlg('Input COM Portadress of Powermeter (e.g. COM1)');  


        end

        % Button pushed function: SelectOutputDirectoryButton
        function SelectOutputDirectoryButtonPushed(app, event)
            outputDir = uigetdir('Please select a directory to save the measured power');
            if outputDir ~= 0 
                app.OutputDirectoryEditField.Value = outputDir;
            end
            focusFigure(app)
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            uiwait(msgbox({'Power Measurement written by Konstantin Neuhaus (konneuhaus@gmail.com).'}, 'About', 'help'))
        end

        % Button pushed function: StartMeasurementButton
        function StartMeasurementButtonPushed(app, event)
            tic;
            clearAxis(app);
            app.stop = 0;
            power_step = app.StepsizeEditField.Value;
            formatOut = 'yyyy_mm_dd';
            date = datestr(now,formatOut);
            averages = app.AveragesEditField.Value;
            app.StartMeasurementButton.BackgroundColor = [0,1,0];
            app.StartMeasurementButton.Enable = 'Off';
            app.StopMeasurementButton.BackgroundColor = [1,0,0];
            app.StopMeasurementButton.Enable = 'On';
            if strcmp(app.MicroscopeDropDown.Value,'Confocal 2') %New Confocal
                % List of Lasers and Dichroics
                laser_list = {'Laser 405-50C','Laser 488-150C','Laser 505-50C','Laser OBIS LS 552-60','Laser OBIS LS 594-60','Laser 640-100C'};
                laser_list_wavelengths = {'L405','L488','L505','L552','L594','L640'};
                laser_list_wavelengths_b = [405,488,505,552,594,640];
                dichroics_list = {'405/488/594','405/488/552/640','505/594'};
                app.LegendTextArea.Value = dichroics_list;
                datapoints = 100/power_step +1;
                app.data = struct('Laser_Percentage_Power', [],'L405', zeros(3,datapoints), 'L488', zeros(3,datapoints),'L505', zeros(3,datapoints),'L552', zeros(3,datapoints),'L594', zeros(3,datapoints),'L640', zeros(3,datapoints));
                app.data(:).Laser_Percentage_Power = 0:power_step:100;
                for laser = 1:length(laser_list)
                    SetWavelength(app.powermeterDevice, laser_list_wavelengths_b(laser)); % Set Wavelength Correction of Powermeter
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- State'}),'Off');
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- Modulation/Trigger'}),'CW (constant power)');
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- State'}),'On');
                    pause(0.1)
                    for dic = 1:3
                        app.mmc.setProperty('CSUW1-Dichroic Mirror','State',num2str(dic-1))
                        m = 1;
                         for power_percentage = 0:power_step:100
                            app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- PowerSetpoint (%)'}),power_percentage);
                            measured_power = average_single_acquisitions(app.powermeterDevice,averages);
                            app.data(1).(char(laser_list_wavelengths(laser)))(dic,m) = measured_power;
                            m = m + 1;
                            updatePlot(app,laser,dic,laser_list_wavelengths);
                            if app.stop
                                break
                            end
                         end
                        if app.stop
                            break
                        end
                         app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- PowerSetpoint (%)'}),0);
                    end
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- PowerSetpoint (%)'}),0);
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- State'}),'Off');
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- Modulation/Trigger'}),'External/Digital');
                    if app.stop
                        break
                    end
                end
                data_export = app.data;
                data_export.microscope = 'Confocal 2';
                data_export.stepsize = power_step;
                data_export.NumLaser = length(laser_list);
                data_export.averages = averages;
                data_export.laser_list_wavelengths = laser_list_wavelengths;
                data_export.dichroics_list = dichroics_list;
                save([app.OutputDirectoryEditField.Value,'\',date,'_laser_power_Confocal2.mat'],'data_export');
            elseif strcmp(app.MicroscopeDropDown.Value,'Confocal 1') % Old Confocal
                datapoints = 100/power_step +1;
                laser_list = {'Laser 405-50C','Laser 488-50C','Laser OBIS LS 514-150','Laser OBIS LS 552-60','Laser OBIS LS 594-100','Laser 637-140C'};
                laser_list_wavelengths = {'L405','L488','L514','L552','L594','L637'};
                laser_list_wavelengths_b = [405,488,514,552,594,637];
                dichroics_list = {'405/488/552/640','505/594','488/552/594'};
                app.LegendTextArea.Value = dichroics_list;
                app.data = struct('Laser_Percentage_Power', [],'L405', zeros(3,datapoints),'L488', zeros(3,datapoints),'L514', zeros(3,datapoints),'L552', zeros(3,datapoints),'L594', zeros(3,datapoints),'L637', zeros(3,datapoints));
                app.data(:).Laser_Percentage_Power =0:power_step:100;
                for laser = 1:length(laser_list)
                    SetWavelength(app.powermeterDevice, laser_list_wavelengths_b(laser)); % Set Wavelength Correction of Powermeter
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- State'}),'Off');
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- Modulation/Trigger'}),'CW (constant power)');
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- State'}),'On');
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- PowerSetpoint (%)'}),0);
                    pause(0.1)
                    for dic = 1:3
                        app.mmc.setProperty('CSUW1-Dichroic Mirror','State',num2str(dic-1))
                        m = 1;
                        average_single_acquisitions(app.powermeterDevice,10);
                         for power_percentage = 0:power_step:100
                            app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- PowerSetpoint (%)'}),power_percentage);
                            average_single_acquisitions(app.powermeterDevice,2);
                            pause(0.1);
                            measured_power = average_single_acquisitions(app.powermeterDevice,averages);
                            app.data(1).(char(laser_list_wavelengths(laser)))(dic,m) = measured_power;
                            m = m + 1;
                            updatePlot(app,laser,dic,laser_list_wavelengths);
                            if app.stop
                                break
                            end
                         end
                         app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- PowerSetpoint (%)'}),0);
                        if app.stop
                            break
                        end
                    end
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- PowerSetpoint (%)'}),0);
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- State'}),'Off');
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- Modulation/Trigger'}),'External/Digital');
                end
                data_export = app.data;
                data_export.microscope = 'Confocal 1';
                data_export.stepsize = power_step;
                data_export.NumLaser = length(laser_list);
                data_export.averages = averages;
                data_export.laser_list_wavelengths = laser_list_wavelengths;
                data_export.dichroics_list = dichroics_list;
                save([app.OutputDirectoryEditField.Value,'\',date,'_laser_power_Confocal1.mat'],'data_export');
            elseif strcmp(app.MicroscopeDropDown.Value,'Confocal 3')  
                laser_list = {'Laser 445-75C','Laser 488-50C','Laser 514-40C','Laser OBIS LS 552-100','Laser 637-140C'};
                laser_list_wavelengths = {'L445','L488','L514','L552','L637'};
                laser_list_wavelengths_b = [445,488,514,552,637];
                dichroics_list = {'405/488/552/640','505/594','445/552/594'};
                app.LegendTextArea.Value = dichroics_list;
                datapoints = 100/power_step +1;
                app.data  = struct('Laser_Percentage_Power', [],'L445', zeros(3,datapoints),'L488', zeros(3,datapoints),'L514', zeros(3,datapoints),...
                    'L552', zeros(3,datapoints),'L637', zeros(3,datapoints));
                app.data(:).Laser_Percentage_Power =0:power_step:100;
                average_single_acquisitions(app.powermeterDevice,10);

                for laser = 1:length(laser_list)
                    SetWavelength(app.powermeterDevice, laser_list_wavelengths_b(laser)); % Set Wavelength Correction of Powermeter
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- State'}),'Off');
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- Modulation/Trigger'}),'CW (constant power)');
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- State'}),'On');
                    pause(0.1)
                    for dic = 1:3
                        disp('dichroic changed')
                        app.mmc.setProperty('CSUW1-Dichroic Mirror','State',num2str(dic-1))
                        m = 1;
                         for power_percentage = 0:power_step:100
                            app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- PowerSetpoint (%)'}),power_percentage);
                            measured_power = average_single_acquisitions(app.powermeterDevice,averages);
                            app.data(1).(char(laser_list_wavelengths(laser)))(dic,m) = measured_power;
                            m = m + 1;
                            updatePlot(app,laser,dic,laser_list_wavelengths);
                            if app.stop
                                break
                            end
                         end
                         app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- PowerSetpoint (%)'}),0);
                         average_single_acquisitions(app.powermeterDevice,10);
                        if app.stop
                            break
                        end
                    end
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- PowerSetpoint (%)'}),0);
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- State'}),'Off');
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- Modulation/Trigger'}),'External/Digital');
                end
                data_export = app.data;
                data_export.microscope = 'Confocal3';
                data_export.stepsize = power_step;
                data_export.NumLaser = length(laser_list);
                data_export.averages = averages;
                data_export.laser_list_wavelengths = laser_list_wavelengths;
                data_export.dichroics_list = dichroics_list;
                save([app.OutputDirectoryEditField.Value,'\',date,'_laser_power_Confocal3.mat'],'data_export');
            elseif strcmp(app.MicroscopeDropDown.Value,'Confocal 4') %New Confocal
                % List of Lasers and Dichroics
                laser_list = {'Laser 405-50C','Laser 488-100C','Laser 514-40C','Laser OBIS LS 552-80','Laser OBIS LS 594-60','Laser 637-140C'};
                laser_list_wavelengths = {'L405','L488','L514','L552','L594','L637'};
                laser_list_wavelengths_b = [405,488,514,552,594,637];
                dichroics_list = {'405/488/552/640','505/594','405/488/594'};
                app.LegendTextArea.Value = dichroics_list;
                datapoints = 100/power_step +1;
                app.data = struct('Laser_Percentage_Power', [],'L405', zeros(3,datapoints), 'L488', zeros(3,datapoints),'L514', zeros(3,datapoints),'L552', zeros(3,datapoints),'L594', zeros(3,datapoints),'L637', zeros(3,datapoints));
                app.data(:).Laser_Percentage_Power = 0:power_step:100;
                for laser = 1:length(laser_list)
                    SetWavelength(app.powermeterDevice, laser_list_wavelengths_b(laser)); % Set Wavelength Correction of Powermeter
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- State'}),'Off');
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- Modulation/Trigger'}),'CW (constant power)');
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- State'}),'On');
                    pause(0.1)
                    for dic = 1:3
                        disp(['Current Laser: ',num2str(laser_list_wavelengths_b(laser)),' Current Dichroic: ',num2str(dic)])
                        app.mmc.setProperty('CSUW1-Dichroic Mirror','State',num2str(dic-1))
                        m = 1;
                         for power_percentage = 0:power_step:100
                            app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- PowerSetpoint (%)'}),power_percentage);
                            if power_percentage == 0
                                pause(5);
                            end
                            measured_power = average_single_acquisitions(app.powermeterDevice,averages);
                            app.data(1).(char(laser_list_wavelengths(laser)))(dic,m) = measured_power;
                            m = m + 1;
                            updatePlot(app,laser,dic,laser_list_wavelengths);
                            if app.stop
                                break
                            end
                         end
                        if app.stop
                            break
                        end
                         app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- PowerSetpoint (%)'}),0);
                    end
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- PowerSetpoint (%)'}),0);
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- State'}),'Off');
                    app.mmc.setProperty('Coherent-Scientific Remote',strjoin({char(laser_list(laser)),'- Modulation/Trigger'}),'External/Digital');
                    if app.stop
                        break
                    end
                end
                disp('Finished Power measurement!')
                data_export = app.data;
                data_export.microscope = 'Confocal4';
                data_export.stepsize = power_step;
                data_export.NumLaser = length(laser_list);
                data_export.averages = averages;
                data_export.laser_list_wavelengths = laser_list_wavelengths;
                data_export.dichroics_list = dichroics_list;
                save([app.OutputDirectoryEditField.Value,'\',date,'_laser_power_Confocal4.mat'],'data_export');
                disp('Data was saved!')
            end
            app.StartMeasurementButton.BackgroundColor = [0.96,0.96,0.96];
            app.StartMeasurementButton.Enable = 'On';
            app.StopMeasurementButton.BackgroundColor = [0.96,0.96,0.96];
            app.StopMeasurementButton.Enable = 'Off';        
            t = toc;
            disp(['Measurement took: ',num2str(t),' s']);
        end

        % Button pushed function: InitPowermeterButton
        function InitPowermeterButtonPushed(app, event)
            port = app.COMPortPowermeterEditField.Value;
            port_ = ['COM',num2str(port)];
            pm = Open_powermeter(powermeter,port_);
            app.powermeterDevice = pm;

%             warning('on', 'MATLAB:serial:fgetl:unsuccessfulRead');
            test_measurement = average_single_acquisitions(app.powermeterDevice,1);
            if isnan(test_measurement)
                Close(app.powermeterDevice);
            else
                app.InitPowermeterButton.BackgroundColor = [0,1,0];
                app.InitPowermeterButton.Enable = 'off';
                disp(['Test power measurement: ',num2str(test_measurement)]);
                app.ClosePowermeterButton.Enable = 'on';
            end

        end

        % Menu selected function: DebugMenu
        function DebugMenuSelected(app, event)
%                             Close(app.powermeter);
%                 app.InitPowermeterButton.BackgroundColor = [0.96,0.96,0.96];
% app.mmc.setProperty('CSUW1-Dichroic Mirror','State',1)
            disp('Debug pressed')

%             app.mmc.setProperty('CSUW1-Dichroic Mirror','State','1')

            disp('Debug pressed')
            assignin('base', 'app', app)
%             app.InitPowermeterButton.Enable = 'on';
%                 measured_power = average_single_acquisitions(app.powermeterDevice,5);
%                 disp(num2str(measured_power))
%                             warning('off', 'MATLAB:serial:fgetl:unsuccessfulRead');
            app.StartMeasurementButton.BackgroundColor = [0.96,0.96,0.96];
            app.StartMeasurementButton.Enable = 'On';
            app.StopMeasurementButton.BackgroundColor = [0.96,0.96,0.96];
            app.StopMeasurementButton.Enable = 'Off';            
        end

        % Value changed function: MicroscopeDropDown
        function MicroscopeDropDownValueChanged(app, event)
            updateMicroscope(app);
        end

        % Button pushed function: StopMeasurementButton
        function StopMeasurementButtonPushed(app, event)
            app.stop = 1;
        end

        % Button pushed function: InitMicromanagerButton
        function InitMicromanagerButtonPushed(app, event)
            app.MM.gui = org.micromanager.internal.MMStudio(false);
            
            try
                app.MM.mmc = app.MM.gui.getCore;
            catch
                app.MM.mmc = app.MM.gui.getCMMCore;
            end
            app.MM.acq = app.MM.gui.getAcquisitionEngine;
            disp('Micro-Manager started.');
            app.mmc = app.MM.mmc;
            app.InitMicromanagerButton.BackgroundColor = [0,1,0];
            app.InitMicromanagerButton.Enable = 'Off';
        end

        % Menu selected function: ImportMenu
        function ImportMenuSelected(app, event)
            [FileName,PathName,FilterIndex] = uigetfile('.mat','Please select power measurement');
            if FileName ~= 0 
                app.data = load(fullfile(PathName,FileName),'data_export');
                load(fullfile(PathName,FileName),'data_export');
                app.OutputDirectoryEditField.Value = PathName;
            end
            focusFigure(app)
            app.data = data_export;
            app.LegendTextArea.Value = data_export.dichroics_list;
            app.data(:).Laser_Percentage_Power = 0:data_export.stepsize:100;
            app.microscope = data_export.microscope;
            app.MicroscopeDropDown.Value = data_export.microscope;
            updateMicroscope(app)
            for laser = 1:data_export.NumLaser
                for d = 1:3
                     updatePlot(app,laser,d,data_export.laser_list_wavelengths)     
                end 
            end
            
            
        end

        % Menu selected function: PlotDataMenu
        function PlotDataMenuSelected(app, event)
            Export_PlotData(app)
        end

        % Button pushed function: ClosePowermeterButton
        function ClosePowermeterButtonPushed(app, event)
            Close(app.powermeter);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LaserPower = uifigure('Visible', 'off');
            app.LaserPower.Name = 'LaserPower';
            app.Menu = uimenu(app.LaserPower);
            app.Menu.Text = 'Menu';
            app.AboutMenu = uimenu(app.Menu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.DebugMenu = uimenu(app.Menu);
            app.DebugMenu.MenuSelectedFcn = createCallbackFcn(app, @DebugMenuSelected, true);
            app.DebugMenu.Text = 'Debug';
            app.ImportMenu = uimenu(app.LaserPower);
            app.ImportMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportMenuSelected, true);
            app.ImportMenu.Text = 'Import';
            app.ExportMenu = uimenu(app.LaserPower);
            app.ExportMenu.Text = 'Export';
            app.PlotDataMenu = uimenu(app.ExportMenu);
            app.PlotDataMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotDataMenuSelected, true);
            app.PlotDataMenu.Text = 'Plot Data';
            app.UIAxes = uiaxes(app.LaserPower);
            title(app.UIAxes, '488nm')
            xlabel(app.UIAxes, 'Laserpower [%]')
            ylabel(app.UIAxes, 'Power [mW]')
            app.UIAxes2 = uiaxes(app.LaserPower);
            title(app.UIAxes2, '505/514nm')
            xlabel(app.UIAxes2, 'Laserpower [%]')
            ylabel(app.UIAxes2, 'Power [mW]')
            app.UIAxes3 = uiaxes(app.LaserPower);
            title(app.UIAxes3, '552nm')
            xlabel(app.UIAxes3, 'Laserpower [%]')
            ylabel(app.UIAxes3, 'Power [mW]')
            app.UIAxes4 = uiaxes(app.LaserPower);
            title(app.UIAxes4, '594nm')
            xlabel(app.UIAxes4, 'Laserpower [%]')
            ylabel(app.UIAxes4, 'Power [mW]')
            app.UIAxes5 = uiaxes(app.LaserPower);
            title(app.UIAxes5, '640')
            xlabel(app.UIAxes5, 'Laserpower [%]')
            ylabel(app.UIAxes5, 'Power [mW]')
            app.UIAxes6 = uiaxes(app.LaserPower);
            title(app.UIAxes6, '405')
            xlabel(app.UIAxes6, 'Laserpower [%]')
            ylabel(app.UIAxes6, 'Power [mW]')
            app.OutputDirectoryEditFieldLabel = uilabel(app.LaserPower);
            app.OutputDirectoryEditFieldLabel.Text = 'Output Directory';
            app.OutputDirectoryEditField = uieditfield(app.LaserPower, 'text');
            app.MicroscopeDropDownLabel = uilabel(app.LaserPower);
            app.MicroscopeDropDownLabel.Text = 'Microscope';
            app.MicroscopeDropDown = uidropdown(app.LaserPower);
            app.MicroscopeDropDown.Items = {'Confocal 1', 'Confocal 2', 'Confocal 3', 'Confocal 4', 'APOM'};
            app.MicroscopeDropDown.ValueChangedFcn = createCallbackFcn(app, @MicroscopeDropDownValueChanged, true);
            app.MicroscopeDropDown.Value = 'Confocal 1';
            app.StepsizeEditFieldLabel = uilabel(app.LaserPower);
            app.StepsizeEditFieldLabel.Text = 'Stepsize [%]';
            app.StepsizeEditField = uieditfield(app.LaserPower, 'numeric');
            app.StepsizeEditField.LowerLimitInclusive = 'off';
            app.StepsizeEditField.Limits = [0 100];
            app.StepsizeEditField.Value = 1;
            app.COMPortPowermeterEditFieldLabel = uilabel(app.LaserPower);
            app.COMPortPowermeterEditFieldLabel.Text = 'COM Port Powermeter';
            app.COMPortPowermeterEditField = uieditfield(app.LaserPower, 'numeric');
            app.COMPortPowermeterEditField.Value = 7;
            app.InitPowermeterButton = uibutton(app.LaserPower, 'push');
            app.InitPowermeterButton.ButtonPushedFcn = createCallbackFcn(app, @InitPowermeterButtonPushed, true);
            app.InitPowermeterButton.Text = 'Init Powermeter';
            app.SelectOutputDirectoryButton = uibutton(app.LaserPower, 'push');
            app.SelectOutputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputDirectoryButtonPushed, true);
            app.SelectOutputDirectoryButton.Text = 'Select Output Directory';
            app.StartMeasurementButton = uibutton(app.LaserPower, 'push');
            app.StartMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @StartMeasurementButtonPushed, true);
            app.StartMeasurementButton.Text = 'Start Measurement';
            app.StopMeasurementButton = uibutton(app.LaserPower, 'push');
            app.StopMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @StopMeasurementButtonPushed, true);
            app.StopMeasurementButton.Text = 'Stop Measurement';
            app.AveragesEditFieldLabel = uilabel(app.LaserPower);
            app.AveragesEditFieldLabel.Text = 'Averages';
            app.AveragesEditField = uieditfield(app.LaserPower, 'numeric');
            app.AveragesEditField.LowerLimitInclusive = 'off';
            app.AveragesEditField.Limits = [0 100];
            app.AveragesEditField.RoundFractionalValues = 'on';
            app.AveragesEditField.Value = 5;
            app.LegendTextAreaLabel = uilabel(app.LaserPower);
            app.LegendTextAreaLabel.Text = 'Legend';
            app.LegendTextArea = uitextarea(app.LaserPower);
            app.InitMicromanagerButton = uibutton(app.LaserPower, 'push');
            app.InitMicromanagerButton.ButtonPushedFcn = createCallbackFcn(app, @InitMicromanagerButtonPushed, true);
            app.InitMicromanagerButton.Text = 'Init Micromanager';
            app.ClosePowermeterButton = uibutton(app.LaserPower, 'push');
            app.ClosePowermeterButton.ButtonPushedFcn = createCallbackFcn(app, @ClosePowermeterButtonPushed, true);
            app.ClosePowermeterButton.Enable = 'off';
            app.ClosePowermeterButton.Text = 'Close Powermeter';
            % Show the figure after all components are created
            app.LaserPower.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PowerMeasurement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LaserPower)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LaserPower)
        end
    end
end